classdef apptrabalho < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        Menu               matlab.ui.container.Menu
        SairMenu           matlab.ui.container.Menu
        AutoresMenu        matlab.ui.container.Menu
        RelatrioMenu       matlab.ui.container.Menu
        GridLayout         matlab.ui.container.GridLayout
        LeftPanel          matlab.ui.container.Panel
        bRESET             matlab.ui.control.Button
        bEXECUTAR          matlab.ui.control.Button
        DadosdoPVIPanel    matlab.ui.container.Panel
        lF                 matlab.ui.control.Lamp
        lY0                matlab.ui.control.Lamp
        lN                 matlab.ui.control.Lamp
        lB                 matlab.ui.control.Lamp
        lA                 matlab.ui.control.Lamp
        eF                 matlab.ui.control.EditField
        FtyEditFieldLabel  matlab.ui.control.Label
        eY0                matlab.ui.control.NumericEditField
        aLabel_4           matlab.ui.control.Label
        eA                 matlab.ui.control.NumericEditField
        aLabel_3           matlab.ui.control.Label
        eN                 matlab.ui.control.NumericEditField
        aLabel_2           matlab.ui.control.Label
        eB                 matlab.ui.control.NumericEditField
        aLabel             matlab.ui.control.Label
        CenterPanel        matlab.ui.container.Panel
        Panel_2            matlab.ui.container.Panel
        UITable            matlab.ui.control.Table
        Panel              matlab.ui.container.Panel
        UIAxes             matlab.ui.control.UIAxes
        RightPanel         matlab.ui.container.Panel
        lEIXOS             matlab.ui.control.Lamp
        sEIXOS             matlab.ui.control.Switch
        EixosLabel         matlab.ui.control.Label
        sTABELA            matlab.ui.control.Switch
        TabelaSwitchLabel  matlab.ui.control.Label
        lTABELA            matlab.ui.control.Lamp
        bgMETODOS          matlab.ui.container.ButtonGroup
        bTODOS             matlab.ui.control.RadioButton
        bRK4               matlab.ui.control.RadioButton
        bODE45             matlab.ui.control.RadioButton
        bOUTRO             matlab.ui.control.RadioButton
        bRK2               matlab.ui.control.RadioButton
        bEULER_M           matlab.ui.control.RadioButton
        bEULER             matlab.ui.control.RadioButton
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: bEXECUTAR
        function bEXECUTARPushed(app, event)
            cla(app.UIAxes)
            syms y(t)
            strF = app.eF.Value;
            f = @(t,y) eval(vectorize(strF));
            a = app.eA.Value;
            b = app.eB.Value;
            n = app.eN.Value;
            y0 = app.eY0.Value;
 
            escolhabgMetodos = app.bgMETODOS.SelectedObject;
            rbMetodoSelecionado = find([app.bEULER,...
                                        app.bEULER_M,...
                                        app.bRK2,...
                                        app.bRK4,...
                                        app.bODE45,...
                                        app.bOUTRO,...
                                        app.bTODOS]==escolhabgMetodos);
 
            erroFuncao = MException("Matlab:bAtualizar:badF",...
                                    "Função inválida.\nIntroduza uma função em t e y." );
 
            erroB = MException("Matlab:bAtualizar:badB",...
                                    "Introduza um valor real para a e b, tal que b seja maior que a." );

            erroN = MException("Matlab:bAtualizar:badN",...
                                    "Introduza um valor natural para n, tal que n seja maior ou igual a 2." );
 
            erroY0 = MException("Matlab:bAtualizar:badY0",...
                                    "Introduza um valor real para y0." );
            
 
            try
                try                             %verificar a f(t,y)
                    z = f(t,y);
                    app.lF.Color = "g";
 
                catch ME
                    app.lF.Color = "r";
                    throw(erroFuncao)
                end
 
                if ~(isreal(b) && b > a)        %verificar os valores de a e b
                    app.lA.Color = "r";
                    app.lB.Color = "r";
                    throw(erroB)
                else
                    app.lA.Color = "g";
                    app.lB.Color = "g";
                end

                if ~(floor(n) && n > 1)         %Verificar o n
                    app.lN.Color = "r";
                    throw(erroN);
                else
                    app.lN.Color = "g";
                end

                if ~(isreal(y0))                %Verificar o y0
                    app.lY0.Color = "r";
                    throw(erroY0);
                else
                    app.lN.Color = "g";
                end
 
 
                sExata = dsolve(diff(y,t)==f(t,y),y(a)==y0);
                g =@(t) eval(vectorize(char(sExata)));
 
                [t,yEuler] = MEuler(f,a,b,n,y0);
                [~,yEuler_m] = MEuler_Melhorado(f,a,b,n,y0);
                [~,yRK2] = MRK2(f,a,b,n,y0);
                [~,yRK4] = MRK4(f,a,b,n,y0);
                [~,yODE45] = ODE45(f,a,b,n,y0);
                [~,yAdams] = Adams_Bashforth(f,a,b,n,y0);
                

                yExata = g(t);
                erroEuler = abs(yExata-yEuler);
                erroEuler_m = abs(yExata - yEuler_m);
                erroRK2 = abs(yExata-yRK2);
                erroRK4 = abs(yExata-yRK4);
                erroODE45 = abs(yExata-yODE45);
                erroAdams = abs(yExata-yAdams);
 
                plot(app.UIAxes,t,yExata,"-*r")
                hold(app.UIAxes,"on")     

                switch rbMetodoSelecionado
                    case 1
                        plot(app.UIAxes,t,yEuler,"-og")
                        matrizResultados = [t.', yExata.', yEuler.',erroEuler.']; 
                        legendaGrafico = ["Exata","Euler"];
                        NomeColunasTabela = ["t","Exata","Euler","ErroEuler"];
                    case 2
                        plot(app.UIAxes,t,yEuler_m,"-sk")
                        matrizResultados = [t.', yExata.', yEuler_m.',erroEuler_m.']; 
                        legendaGrafico = ["Exata","EulerMelhorado"];
                        NomeColunasTabela = ["t","Exata","EulerMelhorado","ErroEulerMelhorado"];
                    case 3
                        plot(app.UIAxes,t,yRK2,"-+b")
                        matrizResultados = [t.', yExata.', yRK2.',erroRK2.']; 
                        legendaGrafico = ["Exata","RK2"];
                        NomeColunasTabela = ["t","Exata","RK2","ErroRK2"]; 
                    case 4
                        plot(app.UIAxes,t,yRK4,"-*y")
                        matrizResultados = [t.', yExata.', yRK4.',erroRK4.']; 
                        legendaGrafico = ["Exata","RK4"];
                        NomeColunasTabela = ["t","Exata","RK4","ErroRK4"];                      
                    case 5
                        plot(app.UIAxes,t,yODE45,"-.m")
                        matrizResultados = [t.', yExata.', yODE45.',erroODE45.']; 
                        legendaGrafico = ["Exata","ODE45"];
                        NomeColunasTabela = ["t","Exata","ODE45","ErroODE45"];                        
                    case 6
                        plot(app.UIAxes,t,yAdams,"-dc")
                        matrizResultados = [t.', yExata.', yAdams.',erroAdams.']; 
                        legendaGrafico = ["Exata","Adams&Bashford"];
                        NomeColunasTabela = ["t","Exata","Adams&Bashford","ErroAdams&Bashford"];                      
                    case 7
                        plot(app.UIAxes,t,yEuler,"-og")
                        plot(app.UIAxes,t,yEuler_m,"-sk")
                        plot(app.UIAxes,t,yRK2,"-+b") 
                        plot(app.UIAxes,t,yRK4,"-*y")
                        plot(app.UIAxes,t,yODE45,"-.m")
                        plot(app.UIAxes,t,yAdams,"-dc")
                        matrizResultados = [t.', yExata.', yEuler.',yEuler_m.'...
                            yRK2.',yRK4.',yODE45.',yAdams.',erroEuler.',erroEuler_m'...
                            erroRK2.',erroRK4.', erroODE45.',erroAdams.']; 
                        legendaGrafico = ["Exata","Euler","EulerMelhorado","RK2"...
                            "RK4","ODE45","Adams&Bashford"];
                        NomeColunasTabela = ["t","Exata","Euler","EulerMelhorado","RK2"...
                            "RK4","ODE45","Adams&Bashford","erroEuler","erroEulerMelhorado","erroRK2"...
                            "erroRK4","erroODE45","erroAdams&Bashford"];                        
 
                end
 
                grid(app.UIAxes,"on")
                legend(app.UIAxes,legendaGrafico)
 
                app.UITable.Data = array2table(matrizResultados);
                app.UITable.ColumnName = NomeColunasTabela;

            catch ME
                errordlg(ME.message,"ERRO","modal")
            end
        end

        % Menu selected function: SairMenu
        function SairMenuSelected(app, event)
            SN = questdlg("Quer sair?","Sair","Sim","Não","Sim");
            if strcmp(SN,"Sim")
                delete(app.UIFigure)
            end
        end

        % Button pushed function: bRESET
        function bRESETPushed(app, event)
            cla(app.UIAxes);
            grid(app.UIAxes,"off");
            legend(app.UIAxes, "off");
            
            app.UITable.Data = "";
            app.UITable.ColumnName = "";
        end

        % Value changed function: sTABELA
        function sTABELAValueChanged(app, event)
            value = app.sTABELA.Value;
            if value == "Off"
                app.UITable.Data = "";
                app.UITable.ColumnName = "";
                app.lTABELA.Color = "r";
            else
                app.lTABELA.Color = "g";
                bEXECUTARPushed(app);
            end
        end

        % Value changed function: sEIXOS
        function sEIXOSValueChanged(app, event)
            value = app.sEIXOS.Value;
            if value == "Off"
                cla(app.UIAxes);
                grid(app.UIAxes,"off");
                legend(app.UIAxes, "off");
                app.lEIXOS.Color = "r";
            else
                app.lEIXOS.Color = "g";
                bEXECUTARPushed(app);
            end
        end

        % Menu selected function: AutoresMenu
        function AutoresMenuSelected(app, event)
            autores
        end

        % Menu selected function: RelatrioMenu
        function RelatrioMenuSelected(app, event)
            if ispc
                winopen('Relatório.pdf');
            else
                open('Relatório.pdf');
            end 
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 220};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.SairMenu = uimenu(app.Menu);
            app.SairMenu.MenuSelectedFcn = createCallbackFcn(app, @SairMenuSelected, true);
            app.SairMenu.Text = 'Sair';
            app.AutoresMenu = uimenu(app.UIFigure);
            app.AutoresMenu.MenuSelectedFcn = createCallbackFcn(app, @AutoresMenuSelected, true);
            app.AutoresMenu.Text = 'Autores';
            app.RelatrioMenu = uimenu(app.UIFigure);
            app.RelatrioMenu.MenuSelectedFcn = createCallbackFcn(app, @RelatrioMenuSelected, true);
            app.RelatrioMenu.Text = 'Relatório';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DadosdoPVIPanel = uipanel(app.LeftPanel);
            app.DadosdoPVIPanel.Title = 'Dados do PVI';
            app.aLabel = uilabel(app.DadosdoPVIPanel);
            app.aLabel.Text = 'b  = ';
            app.eB = uieditfield(app.DadosdoPVIPanel, 'numeric');
            app.eB.Value = 1.5;
            app.aLabel_2 = uilabel(app.DadosdoPVIPanel);
            app.aLabel_2.Text = 'n  = ';
            app.eN = uieditfield(app.DadosdoPVIPanel, 'numeric');
            app.eN.Value = 3;
            app.aLabel_3 = uilabel(app.DadosdoPVIPanel);
            app.aLabel_3.Text = 'a  = ';
            app.eA = uieditfield(app.DadosdoPVIPanel, 'numeric');
            app.aLabel_4 = uilabel(app.DadosdoPVIPanel);
            app.aLabel_4.Text = 'y0  = ';
            app.eY0 = uieditfield(app.DadosdoPVIPanel, 'numeric');
            app.eY0.Value = 2;
            app.FtyEditFieldLabel = uilabel(app.DadosdoPVIPanel);
            app.FtyEditFieldLabel.Text = 'F(t,y) =';
            app.eF = uieditfield(app.DadosdoPVIPanel, 'text');
            app.eF.Value = '-2*t*y';
            app.lA = uilamp(app.DadosdoPVIPanel);
            app.lB = uilamp(app.DadosdoPVIPanel);
            app.lN = uilamp(app.DadosdoPVIPanel);
            app.lY0 = uilamp(app.DadosdoPVIPanel);
            app.lF = uilamp(app.DadosdoPVIPanel);
            app.bEXECUTAR = uibutton(app.LeftPanel, 'push');
            app.bEXECUTAR.ButtonPushedFcn = createCallbackFcn(app, @bEXECUTARPushed, true);
            app.bEXECUTAR.Text = 'Executar';
            app.bRESET = uibutton(app.LeftPanel, 'push');
            app.bRESET.ButtonPushedFcn = createCallbackFcn(app, @bRESETPushed, true);
            app.bRESET.Text = 'Reset';
            app.CenterPanel = uipanel(app.GridLayout);
            app.Panel = uipanel(app.CenterPanel);
            app.UIAxes = uiaxes(app.Panel);
            app.Panel_2 = uipanel(app.CenterPanel);
            app.UITable = uitable(app.Panel_2);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.RightPanel = uipanel(app.GridLayout);
            app.bgMETODOS = uibuttongroup(app.RightPanel);
            app.bgMETODOS.Title = 'Métodos Numéricos';
            app.bEULER = uiradiobutton(app.bgMETODOS);
            app.bEULER.Text = 'Euler';
            app.bEULER.Value = true;
            app.bEULER_M = uiradiobutton(app.bgMETODOS);
            app.bEULER_M.Text = 'Euler Melhorado';
            app.bRK2 = uiradiobutton(app.bgMETODOS);
            app.bRK2.Text = 'RK2';
            app.bOUTRO = uiradiobutton(app.bgMETODOS);
            app.bOUTRO.Text = 'Adams&Bashford';
            app.bODE45 = uiradiobutton(app.bgMETODOS);
            app.bODE45.Text = 'ODE45';
            app.bRK4 = uiradiobutton(app.bgMETODOS);
            app.bRK4.Text = 'RK4';
            app.bTODOS = uiradiobutton(app.bgMETODOS);
            app.bTODOS.Text = 'Todos os métodos';
            app.lTABELA = uilamp(app.RightPanel);
            app.TabelaSwitchLabel = uilabel(app.RightPanel);
            app.TabelaSwitchLabel.Text = 'Tabela:';
            app.sTABELA = uiswitch(app.RightPanel, 'slider');
            app.sTABELA.ValueChangedFcn = createCallbackFcn(app, @sTABELAValueChanged, true);
            app.sTABELA.Value = 'On';
            app.EixosLabel = uilabel(app.RightPanel);
            app.EixosLabel.Text = 'Eixos:';
            app.sEIXOS = uiswitch(app.RightPanel, 'slider');
            app.sEIXOS.ValueChangedFcn = createCallbackFcn(app, @sEIXOSValueChanged, true);
            app.sEIXOS.Value = 'On';
            app.lEIXOS = uilamp(app.RightPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = apptrabalho

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end