classdef AppTrabalho < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Menu                    matlab.ui.container.Menu
        SairMenu                matlab.ui.container.Menu
        AutoresMenu             matlab.ui.container.Menu
        RelatrioMenu            matlab.ui.container.Menu
        GridLayout              matlab.ui.container.GridLayout
        LeftPanel               matlab.ui.container.Panel
        CondiesIniciaisPanel    matlab.ui.container.Panel
        eV0                     matlab.ui.control.NumericEditField
        v0Label                 matlab.ui.control.Label
        eU0                     matlab.ui.control.NumericEditField
        u0Label                 matlab.ui.control.Label
        NmerodeiteraesPanel     matlab.ui.container.Panel
        eN                      matlab.ui.control.NumericEditField
        NLabel                  matlab.ui.control.Label
        IntervaloPanel          matlab.ui.container.Panel
        eB                      matlab.ui.control.NumericEditField
        bLabel                  matlab.ui.control.Label
        eA                      matlab.ui.control.NumericEditField
        aLabel                  matlab.ui.control.Label
        eG                      matlab.ui.control.EditField
        gtuvLabel               matlab.ui.control.Label
        eF                      matlab.ui.control.EditField
        ftuvLabel               matlab.ui.control.Label
        CenterPanel             matlab.ui.container.Panel
        Panel_2                 matlab.ui.container.Panel
        UIAxes                  matlab.ui.control.UIAxes
        Panel                   matlab.ui.container.Panel
        UITable                 matlab.ui.control.Table
        RightPanel              matlab.ui.container.Panel
        sEIXOS                  matlab.ui.control.Switch
        EixosLabel              matlab.ui.control.Label
        sTABELA                 matlab.ui.control.Switch
        TabelaSwitchLabel       matlab.ui.control.Label
        lEIXOS                  matlab.ui.control.Lamp
        lTABELA                 matlab.ui.control.Lamp
        dpPROBLEMAS             matlab.ui.control.DropDown
        ProblemasDropDownLabel  matlab.ui.control.Label
        bATUALIZAR              matlab.ui.control.Button
        bgMETODOS               matlab.ui.container.ButtonGroup
        bTODOS                  matlab.ui.control.RadioButton
        bRK4                    matlab.ui.control.RadioButton
        bRK2                    matlab.ui.control.RadioButton
        bEULERM                 matlab.ui.control.RadioButton
        bEULER                  matlab.ui.control.RadioButton
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: bATUALIZAR
        function bATUALIZARPushed(app, event)
            syms u(t) v(t)

            strF = app.eF.Value;
            f =@(t,u,v) eval(vectorize(strF));

            strG = app.eG.Value;
            g =@(t,u,v) eval(vectorize(strG));
            
            a = app.eA.Value;
            b = app.eB.Value;

            n = app.eN.Value;
            
            u0 = app.eU0.Value;
            v0 = app.eV0.Value;
            
           
           %Exceçoes 
            excepA = MException('Matlab:BadFunction','O valor de a tem de ser superior ao valor de b');
            excepF = MException('Matlab:BadFunction','Introduza uma função de t,u,v');
            excepN = MException('Matlab:BadFunction','Introduza um número real');
            excepNZero = MException('Matlab:BadFunction','O valor de n tem de ser superior a 0');
            nlinear = 0;
            

            try

                try %verifica se o input de F é valido
                    f(t,u,v); 
                    app.eF.FontColor = 'k';
                catch
                    app.eF.FontColor = 'r';
                    throw(excepF)
                end
                
                try %verifica se o input de G é valido
                    g(t,u,v);
                    app.eG.FontColor = 'k';
                catch
                    app.eG.FontColor = 'r';
                    throw(excepF)
                end


                 if ~isreal(n)
                    app.eN.FontColor = 'r';
                    throw(excepN)
                else
                    app.eN.FontColor = 'k';
                end
                
                if(n == 0)
                    app.eN.FontColor = 'r';
                    throw(excepNZero);
                else
                     app.eN.FontColor = 'k';
                end

                if (a>b)
                    app.eA.FontColor  ='r';
                    app.eB.FontColor  ='r';
                    throw(excepA);
                else
                    app.eA.FontColor  ='k';
                    app.eB.FontColor  ='k';
                end
                
                
                try
                    
                    c = subs(str2sym(strF));
                   
                    d = subs(str2sym(strG));
                   
                    
                catch ME
                    %disp("aqui")
                    nlinear = 1;
                    disp(" ");
                    
                    %esta variavel indica se as equações sao lineares ou
                   %não,é util saber isto porque dsolve nao resolve ed's
                   %não lineares logo não é possivel calcular a solução
                   %exata
                end


        %resolução com solução exata
                if(nlinear == 0)
                    eqns = [diff(u,t) == c,diff(v,t) == d];
                    ci = [u(0) == u0,v(0) == v0];
                    [sExataU,~] = dsolve(eqns,ci);
                    h = @(t) eval(vectorize(char(sExataU)));

                    
                    [t,yEuler,~] = M_Euler(f,g,a,b,n,u0,v0);
                    [~,yRK2,~] = MRK2(f,g,a,b,n,u0,v0);
                    [~,yEulerM,~] = MEuler_Melhorado(f,g,a,b,n,u0,v0);
                    [~,yRK4,~] = MRK4(f,g,a,b,n,u0,v0);
                    yExata = h(t);

                    erroEuler = abs(yExata-yEuler);
                    erroRK2 = abs(yExata-yRK2);
                    erroEulerM = abs(yExata-yEulerM);
                    erroRK4 = abs(yExata-yRK4);

                    %plot(app.UIAxes,t,yExata,"-ro")
                    %hold(app.UIAxes,"on")
                    %legend(app.UIAxes,'Exata')

                else
                    disp("aqui")
                    [t,yEuler,~] = M_Euler(f,g,a,b,n,u0,v0);
                    
                    disp("aqui2")
                    [~,yRK2,~] = MRK2(f,g,a,b,n,u0,v0);
                    [~,yEulerM,~] = MEuler_Melhorado(f,g,a,b,n,u0,v0);
                    [~,yRK4,~] = MRK4(f,g,a,b,n,u0,v0);
                    

                end

               % disp("a escolher");
                escolha = app.bgMETODOS.SelectedObject.Text;
                %disp("escolha: " + escolha);
                % 


                switch escolha
                    case 'Todos'
                        if(nlinear == 0)
                            tabela = [t.',yExata.',yEuler.',erroEuler.',yEulerM.',erroEulerM.',yRK2.',erroRK2.',yRK4.',erroRK4.'];
                            T = array2table(tabela);
                            app.UITable.Data = T;
                            hold(app.UIAxes,"on")
                            app.UITable.ColumnName = {'t','Exata','Euler','erroEuler','EulerM','erroEulerM','RK2','erroRK2','RK4','erroRK4'};
                            app.UIAxes.Title.String = "Todos os Métodos";
                            plot(app.UIAxes,t,yEuler,"Color","#4169E1","Marker","*")
                            plot(app.UIAxes,t,yEulerM,"Color",'#111111',"Marker","+")
                            plot(app.UIAxes,t,yRK2,"Color",'#EFC94C',"Marker",">")
                            plot(app.UIAxes,t,yRK4,"Color",'#2F4F4F',"Marker","pentagram")
                            hold(app.UIAxes,"off")
                            grid(app.UIAxes,"on")
                            legend(app.UIAxes,'Exata','Euler','EulerM','RK2','RK4')
                        else
                            tabela = [t.',yEuler.',yEulerM.',yRK2.',yRK4.'];
                            T = array2table(tabela);
                            app.UITable.Data = T;
                            hold(app.UIAxes,"on")
                            app.UITable.ColumnName = {'t','Euler','EulerM','RK2','RK4'};
                            app.UIAxes.Title.String = "Todos os Métodos";
                            plot(app.UIAxes,t,yEuler,"Color","#4169E1","Marker","*")
                            plot(app.UIAxes,t,yEulerM,"Color",'#111111',"Marker","+")
                            plot(app.UIAxes,t,yRK2,"Color",'#EFC94C',"Marker",">")
                            plot(app.UIAxes,t,yRK4,"Color",'#2F4F4F',"Marker","pentagram")
                            hold(app.UIAxes,"off")
                            grid(app.UIAxes,"on")
                            legend(app.UIAxes,'Euler','EulerM','RK2','RK4')
                        end  

                    case 'RK2'
                        if(nlinear == 0)
                            tabela = [t.',yExata.',yRK2.',erroRK2.'];
                            T = array2table(tabela);
                            app.UITable.Data = T;
                            app.UITable.ColumnName = {'t','Exata','RK2','erroRK2'};
                            app.UIAxes.Title.String = "Método de RK2";
                            plot(app.UIAxes,t,yRK2,"Color","#EFC94C","Marker",">")
                            hold(app.UIAxes,"off")
                            grid(app.UIAxes,"on")
                            legend(app.UIAxes,'Exata','RK2')
                        else
                            tabela = [t.',yRK2.'];
                            T = array2table(tabela);
                            app.UITable.Data = T;
                            app.UITable.ColumnName = {'t','RK2'};
                            app.UIAxes.Title.String = "Método de RK2";
                            plot(app.UIAxes,t,yRK2,"Color","#EFC94C","Marker",">")
                            hold(app.UIAxes,"off")
                            grid(app.UIAxes,"on")
                            legend(app.UIAxes,'RK2')
                        end


                    case 'RK4'
                        if(nlinear == 0)
                            tabela = [t.',yExata.',yRK4.',erroRK4.'];
                            T = array2table(tabela);
                            app.UITable.Data = T;
                            app.UITable.ColumnName = {'t','Exata','RK4','erroRK4'};
                            app.UIAxes.Title.String = "Método de RK4";
                            plot(app.UIAxes,t,yRK4,"Color","#2F4F4F","Marker","pentagram")
                            hold(app.UIAxes,"off")
                            grid(app.UIAxes,"on")
                            legend(app.UIAxes,'Exata','RK4')
                        else
                            tabela = [t.',yRK4.'];
                            T = array2table(tabela);
                            app.UITable.Data = T;
                            app.UITable.ColumnName = {'t','RK4'};
                            app.UIAxes.Title.String = "Método de RK4";
                            plot(app.UIAxes,t,yRK4,"Color","#2F4F4F","Marker","pentagram")
                            hold(app.UIAxes,"off")
                            grid(app.UIAxes,"on")
                            legend(app.UIAxes,'RK4')

                        end


                    case 'Euler'
                        if(nlinear == 0)
                            tabela = [t.',yExata.',yEuler.',erroEuler.'];
                            T = array2table(tabela);
                            app.UITable.Data = T;
                            app.UITable.ColumnName = {'t','Exata','Euler','erroEuler'};
                            app.UIAxes.Title.String = "Métodos de euler";
                            plot(app.UIAxes,t,yEuler,"Color","#4169E1","Marker","*")
                            hold(app.UIAxes,"off")
                            grid(app.UIAxes,"on")
                            legend(app.UIAxes,'Exata','Euler')
                        else
                            tabela = [t.',yEuler.'];
                            T = array2table(tabela);
                            app.UITable.Data = T;
                            app.UITable.ColumnName = {'t','Euler'};
                            app.UIAxes.Title.String = "Método de Euler";
                            plot(app.UIAxes,t,yEuler,"Color","#4169E1","Marker","*")
                            hold(app.UIAxes,"off")
                            grid(app.UIAxes,"on")
                            legend(app.UIAxes,'Euler')   
                        end 
                        case 'EulerM'
                            if(nlinear == 0)
                                tabela = [t.',yExata.',yEulerM.',erroEulerM.'];
                                T = array2table(tabela);
                                app.UITable.Data = T;
                                app.UITable.ColumnName = {'t','Exata','EulerM','erroEulerM'};
                                app.UIAxes.Title.String = "Método de Euler Melhorado";
                                plot(app.UIAxes,t,yEulerM,"Color","#111111","Marker","+")
                                hold(app.UIAxes,"off")
                                grid(app.UIAxes,"on")
                                legend(app.UIAxes,'Exata','EulerM')
                            else
                                tabela = [t.',yEulerM.'];
                                T = array2table(tabela);
                                app.UITable.Data = T;
                                app.UITable.ColumnName = {'t','EulerM'};
                                app.UIAxes.Title.String = "Método de Euler Melhorado";
                                plot(app.UIAxes,t,yEulerM,"Color","#111111","Marker","+")
                                hold(app.UIAxes,"off")
                                grid(app.UIAxes,"on")
                                legend(app.UIAxes,'EulerM')
                            end


                end

            catch ME
                errordlg(ME.message,"Erro","modal")
            end


        end

        % Value changed function: dpPROBLEMAS
        function dpPROBLEMASValueChanged(app, event)
            %limpar tabela e eixos
            cla(app.UIAxes);
            grid(app.UIAxes,"off");
            legend(app.UIAxes, "off");
            
            app.UITable.Data = "";
            app.UITable.ColumnName = "";


            value = app.dpPROBLEMAS.Value;
            switch value
                case 'Pêndulo'
                    if(app.eF.Editable == true)
                        app.eF.Editable = false;
                        app.eG.Editable = false;
                        app.eA.Editable = false;
                        app.eB.Editable = false;
                        app.eU0.Editable = false;
                        app.eV0.Editable = false;
                        app.eN.Editable = false;
                    end
                    app.eF.Value = 'v';
                    app.eG.Value = '-sin(u)-0.3*v';
                    app.eA.Value = 0;
                    app.eB.Value = 15;
                    app.eN.Value = 100;
                    app.eU0.Value = pi/2;
                    app.eV0.Value = 0;
                case 'Mola-Massa S/A'
                    if(app.eF.Editable==true)
                        app.eF.Editable = false;
                        app.eG.Editable = false;
                        app.eA.Editable = false;
                        app.eB.Editable = false;
                        app.eU0.Editable = false;
                        app.eV0.Editable = false;
                        app.eN.Editable = false;
                    end
                    app.eF.Value = 'v';
                    app.eG.Value = '-16*u';
                    app.eA.Value = 0;
                    app.eB.Value = 10;
                    app.eN.Value = 100;
                    app.eU0.Value = 9;
                    app.eV0.Value = 0;
                case 'Mola-Massa C/A'
                    if(app.eF.Editable == true)
                        app.eF.Editable = false;
                        app.eG.Editable = false;
                        app.eA.Editable = false;
                        app.eB.Editable = false;
                        app.eU0.Editable = false;
                        app.eV0.Editable = false;
                        app.eN.Editable = false;
                    end
                    app.eF.Value = 'v';
                    app.eG.Value = '-10*v-25*u';
                    app.eA.Value = 0;
                    app.eB.Value = 2;
                    app.eN.Value = 100;
                    app.eU0.Value = 0;
                    app.eV0.Value = -4;
                case 'Modelo Vibratório Mecânico'
                    if(app.eF.Editable == true)
                        app.eF.Editable = false;
                        app.eG.Editable = false;
                        app.eA.Editable = false;
                        app.eB.Editable = false;
                        app.eU0.Editable = false;
                        app.eV0.Editable = false;
                        app.eN.Editable = false;
                        app.Button.Visible = false;
                    end
                    app.eF.Value = 'v';
                    app.eG.Value = '-2*v-2*u+4*cos(t)+2*sin(t)';
                    app.eA.Value = 0;
                    app.eB.Value = 15;
                    app.eN.Value = 100;
                    app.eU0.Value = 0;
                    app.eV0.Value = 3;

                case 'Circuitos Elétricos'
                    if(app.eF.Editable == true)
                        app.eF.Editable = false;
                        app.eG.Editable = false;
                        app.eA.Editable = false;
                        app.eB.Editable = false;
                        app.eU0.Editable = false;
                        app.eV0.Editable = false;
                        app.eN.Editable = false;
                    end
                    app.eF.Value = 'v';
                    app.eG.Value = '-5*v-4*u+2*exp(-t/4)';
                    app.eA.Value = 0;
                    app.eB.Value = 10;
                    app.eN.Value = 100;
                    app.eU0.Value = 0;
                    app.eV0.Value = 0;
                case 'Outro'
                    if(app.eF.Editable == false)
                        app.eF.Editable = true;
                        app.eG.Editable = true;
                        app.eA.Editable = true;
                        app.eB.Editable = true;
                        app.eN.Editable = true;
                        app.eU0.Editable = true;
                        app.eV0.Editable = true;
                    end
            end
 
        end

        % Menu selected function: RelatrioMenu
        function RelatrioMenuSelected(app, event)
            if ispc
                winopen('Relatório.pdf');
            else
                open('Relatório.pdf');
            end 
        end

        % Menu selected function: AutoresMenu
        function AutoresMenuSelected(app, event)
            autores
        end

        % Value changed function: sTABELA
        function sTABELAValueChanged(app, event)
            value = app.sTABELA.Value;
            if value == "Off"
                app.UITable.Data = "";
                app.UITable.ColumnName = "";
                app.lTABELA.Color = "r";
            else
                app.lTABELA.Color = "g";
                bATUALIZARPushed(app);
            end
        end

        % Value changed function: sEIXOS
        function sEIXOSValueChanged(app, event)
            value = app.sEIXOS.Value;
            if value == "Off"
                cla(app.UIAxes);
                grid(app.UIAxes,"off");
                legend(app.UIAxes, "off");
                app.lEIXOS.Color = "r";
            else
                app.lEIXOS.Color = "g";
                bATUALIZARPushed(app);
            end
        end

        % Menu selected function: SairMenu
        function SairMenuSelected(app, event)
            SN = questdlg("Quer sair?","Sair","Sim","Não","Sim");
            if strcmp(SN,"Sim")
                delete(app.UIFigure)
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {512, 512, 512};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {512, 512};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {256, '1x', 218};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.SairMenu = uimenu(app.Menu);
            app.SairMenu.MenuSelectedFcn = createCallbackFcn(app, @SairMenuSelected, true);
            app.SairMenu.Text = 'Sair';
            app.AutoresMenu = uimenu(app.UIFigure);
            app.AutoresMenu.MenuSelectedFcn = createCallbackFcn(app, @AutoresMenuSelected, true);
            app.AutoresMenu.Text = 'Autores';
            app.RelatrioMenu = uimenu(app.UIFigure);
            app.RelatrioMenu.MenuSelectedFcn = createCallbackFcn(app, @RelatrioMenuSelected, true);
            app.RelatrioMenu.Text = 'Relatório';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftPanel.Title = 'Sistemas de Equações Diferenciais';
            app.ftuvLabel = uilabel(app.LeftPanel);
            app.ftuvLabel.Text = 'f(t,u,v) = ';
            app.eF = uieditfield(app.LeftPanel, 'text');
            app.eF.Editable = 'off';
            app.eF.Value = 'v';
            app.gtuvLabel = uilabel(app.LeftPanel);
            app.gtuvLabel.Text = 'g(t,u,v) = ';
            app.eG = uieditfield(app.LeftPanel, 'text');
            app.eG.Editable = 'off';
            app.eG.Value = '-sin(u)-0.3*v';
            app.IntervaloPanel = uipanel(app.LeftPanel);
            app.IntervaloPanel.Title = 'Intervalo';
            app.aLabel = uilabel(app.IntervaloPanel);
            app.aLabel.Text = 'a =';
            app.eA = uieditfield(app.IntervaloPanel, 'numeric');
            app.eA.Editable = 'off';
            app.bLabel = uilabel(app.IntervaloPanel);
            app.bLabel.Text = 'b =';
            app.eB = uieditfield(app.IntervaloPanel, 'numeric');
            app.eB.Editable = 'off';
            app.eB.Value = 15;
            app.NmerodeiteraesPanel = uipanel(app.LeftPanel);
            app.NmerodeiteraesPanel.Title = 'Número de iterações';
            app.NLabel = uilabel(app.NmerodeiteraesPanel);
            app.NLabel.Text = 'n =';
            app.eN = uieditfield(app.NmerodeiteraesPanel, 'numeric');
            app.eN.Editable = 'off';
            app.eN.Value = 100;
            app.CondiesIniciaisPanel = uipanel(app.LeftPanel);
            app.CondiesIniciaisPanel.Title = 'Condições Iniciais';
            app.u0Label = uilabel(app.CondiesIniciaisPanel);
            app.u0Label.Text = 'u0 =';
            app.eU0 = uieditfield(app.CondiesIniciaisPanel, 'numeric');
            app.eU0.Editable = 'off';
            app.eU0.Value = 1.5707963267949;
            app.v0Label = uilabel(app.CondiesIniciaisPanel);
            app.v0Label.Text = 'v0 =';
            app.eV0 = uieditfield(app.CondiesIniciaisPanel, 'numeric');
            app.eV0.Editable = 'off';
            app.CenterPanel = uipanel(app.GridLayout);
            app.Panel = uipanel(app.CenterPanel);
            app.UITable = uitable(app.Panel);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.Panel_2 = uipanel(app.CenterPanel);
            app.UIAxes = uiaxes(app.Panel_2);
            app.RightPanel = uipanel(app.GridLayout);
            app.bgMETODOS = uibuttongroup(app.RightPanel);
            app.bgMETODOS.Title = 'Métodos a Utilizar';
            app.bEULER = uiradiobutton(app.bgMETODOS);
            app.bEULER.Text = 'Euler';
            app.bEULER.Value = true;
            app.bEULERM = uiradiobutton(app.bgMETODOS);
            app.bEULERM.Text = 'EulerM';
            app.bRK2 = uiradiobutton(app.bgMETODOS);
            app.bRK2.Text = 'RK2';
            app.bRK4 = uiradiobutton(app.bgMETODOS);
            app.bRK4.Text = 'RK4';
            app.bTODOS = uiradiobutton(app.bgMETODOS);
            app.bTODOS.Text = 'Todos';
            app.bATUALIZAR = uibutton(app.RightPanel, 'push');
            app.bATUALIZAR.ButtonPushedFcn = createCallbackFcn(app, @bATUALIZARPushed, true);
            app.bATUALIZAR.Text = 'Atualizar';
            app.ProblemasDropDownLabel = uilabel(app.RightPanel);
            app.ProblemasDropDownLabel.Text = 'Problemas:';
            app.dpPROBLEMAS = uidropdown(app.RightPanel);
            app.dpPROBLEMAS.Items = {'Pêndulo', 'Mola-Massa C/A', 'Mola-Massa S/A', 'Circuitos Elétricos', 'Modelo Vibratório Mecânico', 'Outro'};
            app.dpPROBLEMAS.ValueChangedFcn = createCallbackFcn(app, @dpPROBLEMASValueChanged, true);
            app.dpPROBLEMAS.Value = 'Pêndulo';
            app.lTABELA = uilamp(app.RightPanel);
            app.lEIXOS = uilamp(app.RightPanel);
            app.TabelaSwitchLabel = uilabel(app.RightPanel);
            app.TabelaSwitchLabel.Text = 'Tabela:';
            app.sTABELA = uiswitch(app.RightPanel, 'slider');
            app.sTABELA.ValueChangedFcn = createCallbackFcn(app, @sTABELAValueChanged, true);
            app.sTABELA.Value = 'On';
            app.EixosLabel = uilabel(app.RightPanel);
            app.EixosLabel.Text = 'Eixos:';
            app.sEIXOS = uiswitch(app.RightPanel, 'slider');
            app.sEIXOS.ValueChangedFcn = createCallbackFcn(app, @sEIXOSValueChanged, true);
            app.sEIXOS.Value = 'On';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AppTrabalho

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end