classdef appMaquinaCDI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MquinaClculoDiferencialeIntegralUIFigure  matlab.ui.Figure
        Menu                           matlab.ui.container.Menu
        RelatrioMenu                   matlab.ui.container.Menu
        SairMenu                       matlab.ui.container.Menu
        AutoresMenu                    matlab.ui.container.Menu
        TabGroup                       matlab.ui.container.TabGroup
        tgFrVr                         matlab.ui.container.Tab
        pResultados                    matlab.ui.container.Panel
        eIntN                          matlab.ui.control.NumericEditField
        ResultadoIntegralAaBLabel      matlab.ui.control.Label
        SegundaDerivadaLabel           matlab.ui.control.Label
        IntegralLabel                  matlab.ui.control.Label
        DerivadaLabel                  matlab.ui.control.Label
        UITable                        matlab.ui.control.Table
        UIMostrador_2derivada          matlab.ui.control.UIAxes
        UIMostrador_integral           matlab.ui.control.UIAxes
        UIMostrador_derivada           matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
        pDados                         matlab.ui.container.Panel
        lTabela                        matlab.ui.control.Lamp
        lEixos                         matlab.ui.control.Lamp
        bTabela                        matlab.ui.control.Switch
        EixosLabel_2                   matlab.ui.control.Label
        bEixos                         matlab.ui.control.Switch
        EixosLabel                     matlab.ui.control.Label
        OquepretendeCalcularSwitch     matlab.ui.control.Switch
        OquepretendeCalcularSwitchLabel  matlab.ui.control.Label
        IntegraesNumricasButtonGroup   matlab.ui.container.ButtonGroup
        RegradosTrapziosButton         matlab.ui.control.RadioButton
        RegradeSimpsonButton           matlab.ui.control.RadioButton
        DerivaesNumricasButtonGroup_2  matlab.ui.container.ButtonGroup
        DiferenasCentradas3PontosButton  matlab.ui.control.RadioButton
        DiferenasRegressivas2PontosButton_3  matlab.ui.control.RadioButton
        DiferenasRegressivas3PontosButton  matlab.ui.control.RadioButton
        DiferenasProgressivas2PontosButton  matlab.ui.control.RadioButton
        DiferenasProgressivas3PontosButton  matlab.ui.control.RadioButton
        bAtualizar                     matlab.ui.control.Button
        ClculoDiferencialeIntegralPanel  matlab.ui.container.Panel
        bLabel                         matlab.ui.control.Label
        eB                             matlab.ui.control.NumericEditField
        hLabel                         matlab.ui.control.Label
        eH                             matlab.ui.control.NumericEditField
        eA                             matlab.ui.control.NumericEditField
        aLabel                         matlab.ui.control.Label
        eF                             matlab.ui.control.EditField
        fxLabel                        matlab.ui.control.Label
        tgFr2Vr                        matlab.ui.container.Tab
        pResultados_2                  matlab.ui.container.Panel
        UIAxes_2                       matlab.ui.control.UIAxes
        pDados_2                       matlab.ui.container.Panel
        XPanel                         matlab.ui.container.Panel
        eA_2                           matlab.ui.control.NumericEditField
        aLabel_2                       matlab.ui.control.Label
        bLabel_2                       matlab.ui.control.Label
        eB_2                           matlab.ui.control.NumericEditField
        hLabel_2                       matlab.ui.control.Label
        eH_2                           matlab.ui.control.NumericEditField
        YPanel                         matlab.ui.container.Panel
        eA_3                           matlab.ui.control.NumericEditField
        aLabel_3                       matlab.ui.control.Label
        bLabel_3                       matlab.ui.control.Label
        eB_3                           matlab.ui.control.NumericEditField
        hLabel_3                       matlab.ui.control.Label
        eH_3                           matlab.ui.control.NumericEditField
        eHarmonica                     matlab.ui.control.EditField
        DerivaesNumricasButtonGroup    matlab.ui.container.ButtonGroup
        DerivadaparcialordemaxButton   matlab.ui.control.RadioButton
        DerivadaparcialordemayButton   matlab.ui.control.RadioButton
        bAtualizar_2                   matlab.ui.control.Button
        FunoRealde2VariveisReaisPanel  matlab.ui.container.Panel
        eF_2                           matlab.ui.control.EditField
        fxyLabel                       matlab.ui.control.Label
        UIMostrador_2                  matlab.ui.control.UIAxes
    end


    methods (Access = public)

        function YN = isHarmonica(~,f)
            % syms z(x,y)
            % if (diff(f,x,2)+diff(f,y,2)==0)
            %     YN = 1;
            % else
            %     YN = 0;
            % end

            syms x y;  % define x e y como variáveis simbólicas

            % calcula as segundas derivadas parciais
            second_deriv_x = diff(f, x, 2);
            second_deriv_y = diff(f, y, 2);

            % soma as segundas derivadas parciais
            sum_deriv = simplify(second_deriv_x + second_deriv_y);

            % verifica se a soma é zero
            if isAlways(sum_deriv == 0)
                YN = 1;
            else
                YN = 0;
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.RegradosTrapziosButton.Enable = false;
            app.RegradeSimpsonButton.Enable = false;
            app.ResultadoIntegralAaBLabel.Visible = false;
            app.eIntN.Visible = false;
        end

        % Button pushed function: bAtualizar
        function bAtualizarButtonPushed(app, event)
            syms y(x)
            strF = app.eF.Value;
            f = @(x) eval(vectorize(strF));
            a = app.eA.Value;
            h = app.eH.Value;
            b = app.eB.Value;
            
            testeF = MException('Matlab:BadFunction','Introduza uma função em x');
            testeAReal=MException('MATLAB:MyAtualizar:badNReal','Valor de "a" não introduzido ou não real');
            testeBReal=MException('MATLAB:MyAtualizar:badNReal','Valor de "b" não introduzido ou não real'); 
            testeHReal=MException('MATLAB:MyAtualizar:badNReal','Valor de "h" não introduzido ou não real');
            testeAmaiorB=MException('MATLAB:BadFunction','Valor de "a" tem de ser MENOR que "b"');
            
            try 
                dydxSym = diff(f(x));
                sedydxSym = diff(f(x),2);  %calcula s segunda derivada
                g =@(x) eval(vectorize(char(dydxSym)));
                o =@(x) eval(vectorize(char(sedydxSym)));
                
              %Verificações de testes 
              
              try
                  z = f(x);
                catch ME
                    throw(testeF)
              end

                if ~(isscalar(a) && isreal(a))  %se A não for um numero ou se não for real  
                    app.eA.BackgroundColor = 'w';
                    app.eA.FontColor = 'r';
                    throw(testeAReal);
                else
                    app.eA.BackgroundColor = 'w';
                    app.eA.FontColor = 'k';
                end

                if ~(isscalar(b) && isreal(b)) %se B não for um numero ou se não for real
                    app.eB.BackgroundColor = 'w';
                    app.eB.FontColor = 'r';
                    throw(testeBReal);
                else
                    app.eB.BackgroundColor = 'w';
                    app.eB.FontColor = 'k';
                end
                if ~(isscalar(h) && isreal(h)) %se h não for um numero ou se não for real
                    app.eH.BackgroundColor = 'w';
                    app.eH.FontColor = 'r';
                    throw(testeHReal);
                else
                    app.eH.BackgroundColor = 'w';
                    app.eH.FontColor = 'k';
                end
                
                if(a>b || a==b)
                    app.eA.BackgroundColor = 'w';
                    app.eA.FontColor = 'r';
                    app.eB.BackgroundColor = 'w';
                    app.eB.FontColor = 'r';
                    throw(testeAmaiorB);
                else
                    app.eA.BackgroundColor = 'w';
                    app.eA.FontColor = 'k';
                    app.eB.BackgroundColor = 'w';
                    app.eB.FontColor = 'k';
                end

              %%
                escolhamenu = app.OquepretendeCalcularSwitch.Value;
                escolha1 = app.DerivaesNumricasButtonGroup_2.SelectedObject.Text;
                if(escolhamenu == "Derivações Numéricas")
                    switch escolha1
                        case 'Diferenças Progressivas 2 Pontos'
                            [x,y,dydxDF2_P] = DF2_P(f,a,b,h);
                            dydxExata = g(x);
                            sedydxExata = o(x);
                            erroDF2_P = abs(dydxExata -dydxDF2_P);
                            tabela = array2table([x.',y.',dydxExata.', dydxDF2_P.',erroDF2_P.',sedydxExata.']);
                            app.UITable.Data = tabela;
                            app.UITable.ColumnName = ({'x','y','dydxExata','dydxDFP2','erroDFP2','2ªDerivada'});
    
                            plot(app.UIAxes,x,y,"-k+")
                            hold(app.UIAxes,"on")
                            plot(app.UIAxes,x,dydxExata,"Color","r","Marker","square")
                            plot(app.UIAxes,x,dydxDF2_P,"Color","g","Marker","pentagram")
                            plot(app.UIAxes,x,sedydxExata,"Color","b","Marker","diamond")
                            hold(app.UIAxes,"on")

                            legend(app.UIAxes,'y','dydxExato','dxdyDFP2','2ª derivada')
    
    
                        case 'Diferenças Regressivas 2 Pontos'
                            [x,y,dydxDF2_R] = DF2_R(f,a,b,h);
                            dydxExata = g(x);
                            sedydxExata = o(x);
                            erroDF2_R = abs(dydxExata -dydxDF2_R);
                            tabela = array2table([x.',y.',dydxExata.', dydxDF2_R.',erroDF2_R.',sedydxExata.']);
                            app.UITable.Data = tabela;
                            app.UITable.ColumnName = ({'x','y','dydxExata','dydxDFR2','erroDFR2','2ªDerivada'});
    
                            plot(app.UIAxes,x,y,"-k+")
                            hold(app.UIAxes,"on")
                            plot(app.UIAxes,x,dydxExata,"Color","r","Marker","square")
                            plot(app.UIAxes,x,dydxDF2_R,"Color","g","Marker","pentagram")
                            plot(app.UIAxes,x,sedydxExata,"Color","b","Marker","diamond")
                            hold(app.UIAxes,"on")
    
    
                            legend(app.UIAxes,'y','dydxExato','dxdyDFR2','2ª derivada')
    
                        case 'Diferenças Progressivas 3 Pontos'
                            [x,y,dydxDF3_P] = DF3_P(f,a,b,h);
                            dydxExata = g(x);
                            sedydxExata = o(x);
                            erroDF3_P = abs(dydxExata -dydxDF3_P);
                            
                            tabela = array2table([x.',y.',dydxExata.', dydxDF3_P.',erroDF3_P.',sedydxExata.']);
                            app.UITable.Data = tabela;
                            app.UITable.ColumnName = ({'x','y','dydxExata','dydxDFP3','erroDFP3','2ª derivada'});
    
                            plot(app.UIAxes,x,y,"-k+")
                            hold(app.UIAxes,"on")
                            plot(app.UIAxes,x,dydxExata,"Color","r","Marker","square")
                            plot(app.UIAxes,x,dydxDF3_P,"Color","g","Marker","pentagram")
                            plot(app.UIAxes,x,sedydxExata,"Color","b","Marker","diamond")
                            hold(app.UIAxes,"on")
    
    
                            legend(app.UIAxes,'y','dydxExato','dxdyDFP3','2ª derivada')
    
                        case 'Diferenças Regressivas 3 Pontos'
                            [x,y,dydxDF3_R] = DF3_R(f,a,b,h);
                            dydxExata = g(x);
                            sedydxExata = o(x);
                            erroDF3_R = abs(dydxExata -dydxDF3_R);
                            tabela = array2table([x.',y.',dydxExata.', dydxDF3_R.',erroDF3_R.',sedydxExata.']);
                            app.UITable.Data = tabela;
                            app.UITable.ColumnName = ({'x','y','dydxExata','dydxDFR3','erroDFR3','2ª derivada'});
    
                            plot(app.UIAxes,x,y,"-k+")
                            hold(app.UIAxes,"on")
                            plot(app.UIAxes,x,dydxExata,"Color","r","Marker","square")
                            plot(app.UIAxes,x,dydxDF3_R,"Color","g","Marker","pentagram")
                            plot(app.UIAxes,x,sedydxExata,"Color","b","Marker","diamond")
                            hold(app.UIAxes,"on")
                            
    
                            legend(app.UIAxes,'y','dydxExato','dxdyDFR3','2ª derivada')
    
                        case 'Diferenças Centradas 3 Pontos'
                            [x,y,dydxDF3_C] = DF3_C(f,a,b,h);
                            dydxExata = g(x);
                            sedydxExata = o(x);
                            erroDF3_C = abs(dydxExata -dydxDF3_C);
                            tabela = array2table([x.',y.',dydxExata.', dydxDF3_C.',erroDF3_C.',sedydxExata.']);
                            app.UITable.Data = tabela;
                            app.UITable.ColumnName = ({'x','y','dydxExata','dydxDFC3','erroDFC3','2ª derivada'});
    
                            plot(app.UIAxes,x,y,"-k+")
                            hold(app.UIAxes,"on")
                            plot(app.UIAxes,x,dydxExata,"Color","r","Marker","square")
                            plot(app.UIAxes,x,dydxDF3_C,"Color","g","Marker","pentagram")
                            plot(app.UIAxes,x,sedydxExata,"Color","b","Marker","diamond")
                            hold(app.UIAxes,"on")
    
                            legend(app.UIAxes,'y','dydxExato','dxdyDFC3','2ª derivada')
                    end
                else
                
                
                    n = (b-a)/h;
                    escolha2 = app.IntegraesNumricasButtonGroup.SelectedObject.Text;
    
                    switch escolha2
                        case 'Regra dos Trapézios'
                            T = Trapezios(f,a,b,n);
                            app.eIntN.Value = T;
                            [x,y,~] = DF2_P(f,a,b,h);
                            area(app.UIAxes,x,y,"FaceColor","k","FaceAlpha",0.2)
                            legend(app.UIAxes,'Area Integral')
                            
                            
                        case 'Regra de Simpson'
                            S = Simpson(f,a,b,n);
                            app.eIntN.Value = S;
                            [x,y,~] = DF2_P(f,a,b,h);
                            area(app.UIAxes,x,y,"FaceColor","k","FaceAlpha",0.2)
                            legend(app.UIAxes,'Area Integral')
                    end
                end
                    sExata = int(f(sym('x')));
                    %q = integral(f,a,b);
    
                    cla(app.UIMostrador_derivada)
                    cla(app.UIMostrador_integral)
                    cla(app.UIMostrador_2derivada)
                    formaLatex =['$','\frac{dy}{dx}=',latex(dydxSym),'$'];
                    text(app.UIMostrador_derivada,0.1,0.5,formaLatex,"Interpreter","latex","FontSize",18);
                    formaLatex =['$','\int_a^b',app.eF.Value,'=',latex(sExata),'$'];
                    text(app.UIMostrador_integral,0.1,0.5,formaLatex,"Interpreter","latex","FontSize",18);
                    formaLatex =['$','\frac{d^2y}{dx^2}=',latex(sedydxSym),'$'];
                    text(app.UIMostrador_2derivada,0.1,0.5,formaLatex,"Interpreter","latex","FontSize",18);
    
                    hold(app.UIAxes,"off")
    
                catch ME
                    errordlg(ME.message,"ERRO","modal")
            end
        end

        % Button pushed function: bAtualizar_2
        function bAtualizar_2ButtonPushed(app, event)
            syms x y f(x,y)

            strFxy = app.eF_2.Value;
            f =@(x,y) eval(vectorize(strFxy));
            
            testeF = MException('Matlab:BadFunction','Introduza uma função em x e y');

            try
                partialdydxSymx = diff(f(x,y),x);
                partialdydxSymy = diff(f(x,y),y);
            catch ME
                throw(testeF)
            end

            a = app.eA_2.Value;
            h1 = app.eH_2.Value;
            b = app.eB_2.Value;

            a2 = app.eA_3.Value;
            h2 = app.eH_3.Value;
            b2 = app.eB_3.Value;
           
            testeAReal = MException('MATLAB:MyAtualizar:badNReal','Valor de "a" não introduzido ou não real');
            testeBReal = MException('MATLAB:MyAtualizar:badNReal','Valor de "b" não introduzido ou não real'); 
            testeHReal = MException('MATLAB:MyAtualizar:badNReal','Valor de "h" não introduzido ou não real');
            testeAmaiorB = MException('MATLAB:BadFunction','Valor de "a" tem de ser MENOR que "b"');

            try
       %Verificações de testes
%                  try
%                     z = f(x,y);
%                    
%                 catch 
%                                 
%                     throw(testeF)
%                 end


                if ~(isscalar(a) && isreal(a))  %se A não for um numero ou se não for real
                    app.eA_2.BackgroundColor = 'w';
                    app.eA_2.FontColor = 'r';
                    throw(testeAReal);
                else
                    app.eA_2.BackgroundColor = 'w';
                    app.eA_2.FontColor = "k";
                end

                if ~(isscalar(b) && isreal(b)) %se B não for um numero ou se não for real
                    app.eB_2.BackgroundColor = 'w';
                    app.eB_2.FontColor = 'r';
                    throw(testeBReal);
                else
                    app.eB_2.BackgroundColor = 'w';
                    app.eB_2.FontColor = 'k';
                end
                if ~(isscalar(h1) && isreal(h1)) %se h não for um numero ou se não for real
                    app.eH_2.BackgroundColor = 'w';
                    app.eH_2.FontColor = 'r';
                    throw(testeHReal);
                else
                    app.eH_2.BackgroundColor = 'w';
                    app.eH_2.FontColor = 'k';
                end

                if (a>b || a==b) % se a>b ou a == b
                    app.eA_2.BackgroundColor = 'w';
                    app.eA_2.FontColor = 'r';
                    app.eB_2.BackgroundColor = 'w';
                    app.eB_2.FontColor = 'r';
                    throw(testeAmaiorB);
                else
                    app.eA_2.BackgroundColor = 'w';
                    app.eA_2.FontColor = 'k';
                    app.eB_2.BackgroundColor = 'w';
                    app.eB_2.FontColor = 'k';
                end
                 
              %% 2-Box
              
              if ~(isscalar(a) && isreal(a2))  %se A não for um numero ou se não for real
                  app.eA_3.BackgroundColor = 'w';
                  app.eA_3.FontColor = 'r';
                  throw(testeAReal);
              else
                  app.eA_3.BackgroundColor = 'w';
                  app.eA_3.FontColor = 'k';
              end

              if ~(isscalar(b2) && isreal(b2)) %se B não for um numero ou se não for real
                  app.eB_3.BackgroundColor = 'w';
                  app.eB_3.FontColor = 'r';
                  throw(testeBReal);
              else
                  app.eB_3.BackgroundColor = 'w';
                  app.eB_3.FontColor = 'k';
              end
              if ~(isscalar(h2) && isreal(h2)) %se h não for um numero ou se não for real
                  app.eH_3.BackgroundColor = 'w';
                  app.eH_3.FontColor = 'r';
                  throw(testeHReal);
              else
                  app.eH_3.BackgroundColor = 'w';
                  app.eH_3.FontColor = 'k';
              end

              if(a2>b2 || a2==b2)
                  app.eA_3.BackgroundColor = 'w';
                  app.eA_3.FontColor = 'r';
                  app.eB_3.BackgroundColor = 'w';
                  app.eB_3.FontColor = 'r';
                  if(a2 == b2)
                        throw(testeAigual)
                  end
                  throw(testeAmaiorB);
              else
                  app.eA_3.BackgroundColor = 'w';
                  app.eA_3.FontColor = 'k';
                  app.eB_3.BackgroundColor = 'w';
                  app.eB_3.FontColor = 'k';
              end
              
            %%
              [x,y] = meshgrid(a:h1:b, a2:h2:b2);
              z = f(x,y);
              mesh(app.UIAxes_2, x,y,z)

              if isHarmonica(app,f) == 1
                  strHarmonica = "A FUNÇÃO F É HARMÓNICA";
                  corHarmonica = "g";  %coloca a verde se e harmonica
              else
                  disp(isHarmonica(app,f))
                  strHarmonica = "A FUNÇÃO F NÃO É HARMÓNICA";
                  corHarmonica = "r";  %coloca a vermelho se nao
              end
              
              app.eHarmonica.Value = strHarmonica;
              app.eHarmonica.FontColor = corHarmonica;
              
              escolha3 = app.DerivaesNumricasButtonGroup.SelectedObject.Text;
              switch escolha3
                  case 'Derivada parcial ordem a x'
                      cla(app.UIMostrador_2)
                      formaLatex =['$','\frac{\partial f}{\partial x}=\frac{\partial ',strFxy,'}{\partial x}=',latex(partialdydxSymx),'$'];
                      text(app.UIMostrador_2,0.1,0.5,formaLatex,"Interpreter","latex","FontSize",18);
                  case 'Derivada parcial ordem a y'
                      cla(app.UIMostrador_2)
                      formaLatex =['$','\frac{\partial f}{\partial y}=\frac{\partial ',strFxy,'}{\partial y}=',latex(partialdydxSymy),'$'];
                      text(app.UIMostrador_2,0.1,0.5,formaLatex,"Interpreter","latex","FontSize",18);
              end
            catch ME
                errordlg(ME.message,"ERRO","modal")
            end
        end

        % Value changed function: OquepretendeCalcularSwitch
        function OquepretendeCalcularSwitchValueChanged(app, event)
            value = app.OquepretendeCalcularSwitch.Value;
            if(value == "Derivações Numéricas")
                %limpar eixos e tabela
                app.UITable.Data = "";
                app.UITable.ColumnName = "";
                cla(app.UIAxes);
                grid(app.UIAxes,"off");
                legend(app.UIAxes, "off");

                app.bTabela.Enable= true;
                app.lTabela.Enable=true;
                app.lTabela.Color="g";
                app.UITable.Visible = true;
                app.DiferenasProgressivas2PontosButton.Enable = true;
                app.DiferenasProgressivas3PontosButton.Enable = true;
                app.DiferenasRegressivas2PontosButton_3.Enable = true;
                app.DiferenasRegressivas3PontosButton.Enable = true;
                app.DiferenasCentradas3PontosButton.Enable = true;
                app.RegradosTrapziosButton.Enable = false;
                app.RegradeSimpsonButton.Enable = false;
                app.ResultadoIntegralAaBLabel.Visible = false;
                app.eIntN.Visible = false;
            end
            if(value == "Integrações Numéricas")
                %limpar eixos e tabela
                app.UITable.Data = "";
                app.UITable.ColumnName = "";
                cla(app.UIAxes);
                grid(app.UIAxes,"off");
                legend(app.UIAxes, "off");

                app.bTabela.Enable= false;
                app.lTabela.Enable=false;
                app.lTabela.Color="#808080";
                app.UITable.Visible = false;
                app.DiferenasProgressivas2PontosButton.Enable = false;
                app.DiferenasProgressivas3PontosButton.Enable = false;
                app.DiferenasRegressivas2PontosButton_3.Enable = false;
                app.DiferenasRegressivas3PontosButton.Enable = false;
                app.DiferenasCentradas3PontosButton.Enable = false;
                app.RegradosTrapziosButton.Enable = true;
                app.RegradeSimpsonButton.Enable = true;
                app.ResultadoIntegralAaBLabel.Visible = true;
                app.eIntN.Visible = true;
            end
        end

        % Menu selected function: SairMenu
        function SairMenuSelected(app, event)
            SN = questdlg('Deseja mesmo fechar a aplicação?','SAIR','Sim','Não','Não');
            if strcmp(SN,'Sim')
                delete(app.MquinaClculoDiferencialeIntegralUIFigure)
            end
        end

        % Menu selected function: RelatrioMenu
        function RelatrioMenuSelected(app, event)
            if ispc
                winopen('Relatorio_Atividade05.pdf');
            else
                open('Relatorio_Atividade05.pdf');
            end
        end

        % Menu selected function: AutoresMenu
        function AutoresMenuSelected(app, event)
            autores
        end

        % Value changed function: bEixos
        function bEixosValueChanged(app, event)
            value = app.bEixos.Value;
            if value == "Off"
                cla(app.UIAxes);
                grid(app.UIAxes,"off");
                legend(app.UIAxes, "off");
                app.lEixos.Color = "r";
            else
                app.lEixos.Color = "g";
                bAtualizarButtonPushed(app);
            end
        end

        % Value changed function: bTabela
        function bTabelaValueChanged(app, event)
            value = app.bTabela.Value;
            if value == "Off"
                app.UITable.Data = "";
                app.UITable.ColumnName = "";
                app.lTabela.Color = "r";
            else
                app.lTabela.Color = "g";
                bAtualizarButtonPushed(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MquinaClculoDiferencialeIntegralUIFigure = uifigure('Visible', 'off');
            app.MquinaClculoDiferencialeIntegralUIFigure.Name = 'Máquina Cálculo Diferencial e Integral';
            app.Menu = uimenu(app.MquinaClculoDiferencialeIntegralUIFigure);
            app.Menu.Text = '| Menu';
            app.RelatrioMenu = uimenu(app.Menu);
            app.RelatrioMenu.MenuSelectedFcn = createCallbackFcn(app, @RelatrioMenuSelected, true);
            app.RelatrioMenu.Text = '| Relatório';
            app.SairMenu = uimenu(app.Menu);
            app.SairMenu.MenuSelectedFcn = createCallbackFcn(app, @SairMenuSelected, true);
            app.SairMenu.Text = '| Sair';
            app.AutoresMenu = uimenu(app.MquinaClculoDiferencialeIntegralUIFigure);
            app.AutoresMenu.MenuSelectedFcn = createCallbackFcn(app, @AutoresMenuSelected, true);
            app.AutoresMenu.Text = 'Autores';
            app.TabGroup = uitabgroup(app.MquinaClculoDiferencialeIntegralUIFigure);
            app.tgFrVr = uitab(app.TabGroup);
            app.tgFrVr.Title = 'Função real de variável real y = f(x)';
            app.pDados = uipanel(app.tgFrVr);
            app.ClculoDiferencialeIntegralPanel = uipanel(app.pDados);
            app.ClculoDiferencialeIntegralPanel.Title = 'Cálculo Diferencial e Integral ';
            app.fxLabel = uilabel(app.ClculoDiferencialeIntegralPanel);
            app.fxLabel.Text = 'f(x) =';
            app.eF = uieditfield(app.ClculoDiferencialeIntegralPanel, 'text');
            app.eF.Value = 'sin(x)';
            app.aLabel = uilabel(app.ClculoDiferencialeIntegralPanel);
            app.aLabel.Text = 'a';
            app.eA = uieditfield(app.ClculoDiferencialeIntegralPanel, 'numeric');
            app.eH = uieditfield(app.ClculoDiferencialeIntegralPanel, 'numeric');
            app.eH.Value = 0.1;
            app.hLabel = uilabel(app.ClculoDiferencialeIntegralPanel);
            app.hLabel.Text = 'h';
            app.eB = uieditfield(app.ClculoDiferencialeIntegralPanel, 'numeric');
            app.eB.Value = 6.28318530717959;
            app.bLabel = uilabel(app.ClculoDiferencialeIntegralPanel);
            app.bLabel.Text = 'b';
            app.bAtualizar = uibutton(app.pDados, 'push');
            app.bAtualizar.ButtonPushedFcn = createCallbackFcn(app, @bAtualizarButtonPushed, true);
            app.bAtualizar.Text = 'Atualizar';
            app.DerivaesNumricasButtonGroup_2 = uibuttongroup(app.pDados);
            app.DerivaesNumricasButtonGroup_2.Title = 'Derivações Numéricas';
            app.DiferenasProgressivas3PontosButton = uiradiobutton(app.DerivaesNumricasButtonGroup_2);
            app.DiferenasProgressivas3PontosButton.Text = 'Diferenças Progressivas 3 Pontos';
            app.DiferenasProgressivas2PontosButton = uiradiobutton(app.DerivaesNumricasButtonGroup_2);
            app.DiferenasProgressivas2PontosButton.Text = 'Diferenças Progressivas 2 Pontos';
            app.DiferenasProgressivas2PontosButton.Value = true;
            app.DiferenasRegressivas3PontosButton = uiradiobutton(app.DerivaesNumricasButtonGroup_2);
            app.DiferenasRegressivas3PontosButton.Text = 'Diferenças Regressivas 3 Pontos';
            app.DiferenasRegressivas2PontosButton_3 = uiradiobutton(app.DerivaesNumricasButtonGroup_2);
            app.DiferenasRegressivas2PontosButton_3.Text = 'Diferenças Regressivas 2 Pontos';
            app.DiferenasCentradas3PontosButton = uiradiobutton(app.DerivaesNumricasButtonGroup_2);
            app.DiferenasCentradas3PontosButton.Text = 'Diferenças Centradas 3 Pontos';
            app.IntegraesNumricasButtonGroup = uibuttongroup(app.pDados);
            app.IntegraesNumricasButtonGroup.Title = 'Integrações Numéricas';
            app.RegradeSimpsonButton = uiradiobutton(app.IntegraesNumricasButtonGroup);
            app.RegradeSimpsonButton.Text = 'Regra de Simpson';
            app.RegradosTrapziosButton = uiradiobutton(app.IntegraesNumricasButtonGroup);
            app.RegradosTrapziosButton.Text = 'Regra dos Trapézios';
            app.RegradosTrapziosButton.Value = true;
            app.OquepretendeCalcularSwitchLabel = uilabel(app.pDados);
            app.OquepretendeCalcularSwitchLabel.Text = 'O que pretende Calcular?';
            app.OquepretendeCalcularSwitch = uiswitch(app.pDados, 'slider');
            app.OquepretendeCalcularSwitch.Items = {'Derivações Numéricas', 'Integrações Numéricas'};
            app.OquepretendeCalcularSwitch.ValueChangedFcn = createCallbackFcn(app, @OquepretendeCalcularSwitchValueChanged, true);
            app.OquepretendeCalcularSwitch.Value = 'Derivações Numéricas';
            app.EixosLabel = uilabel(app.pDados);
            app.EixosLabel.Text = 'Eixos :';
            app.bEixos = uiswitch(app.pDados, 'slider');
            app.bEixos.ValueChangedFcn = createCallbackFcn(app, @bEixosValueChanged, true);
            app.bEixos.Value = 'On';
            app.EixosLabel_2 = uilabel(app.pDados);
            app.EixosLabel_2.Text = 'Tabela :';
            app.bTabela = uiswitch(app.pDados, 'slider');
            app.bTabela.ValueChangedFcn = createCallbackFcn(app, @bTabelaValueChanged, true);
            app.bTabela.Value = 'On';
            app.lEixos = uilamp(app.pDados);
            app.lTabela = uilamp(app.pDados);
            app.pResultados = uipanel(app.tgFrVr);
            app.UIAxes = uiaxes(app.pResultados);
            title(app.UIAxes, {'Gráfico de uma Função Real de Variável Real'; ''})
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIMostrador_derivada = uiaxes(app.pResultados);
            app.UIMostrador_derivada.XTick = [];
            app.UIMostrador_derivada.YTick = [];
            app.UIMostrador_integral = uiaxes(app.pResultados);
            app.UIMostrador_integral.XTick = [];
            app.UIMostrador_integral.YTick = [];
            app.UIMostrador_2derivada = uiaxes(app.pResultados);
            app.UIMostrador_2derivada.XTick = [];
            app.UIMostrador_2derivada.YTick = [];
            app.UITable = uitable(app.pResultados);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.DerivadaLabel = uilabel(app.pResultados);
            app.DerivadaLabel.Text = 'Derivada';
            app.IntegralLabel = uilabel(app.pResultados);
            app.IntegralLabel.Text = 'Integral';
            app.SegundaDerivadaLabel = uilabel(app.pResultados);
            app.SegundaDerivadaLabel.Text = 'Segunda Derivada';
            app.ResultadoIntegralAaBLabel = uilabel(app.pResultados);
            app.ResultadoIntegralAaBLabel.Text = 'Resultado Integral A a B';
            app.eIntN = uieditfield(app.pResultados, 'numeric');
            app.eIntN.Editable = 'off';
            app.tgFr2Vr = uitab(app.TabGroup);
            app.tgFr2Vr.Title = 'Função real de 2 variáveis reais z = f(x,y)';
            app.pDados_2 = uipanel(app.tgFr2Vr);
            app.UIMostrador_2 = uiaxes(app.pDados_2);
            app.UIMostrador_2.XTick = [];
            app.UIMostrador_2.YTick = [];
            app.FunoRealde2VariveisReaisPanel = uipanel(app.pDados_2);
            app.FunoRealde2VariveisReaisPanel.Title = 'Função Real de 2 Variáveis Reais';
            app.fxyLabel = uilabel(app.FunoRealde2VariveisReaisPanel);
            app.fxyLabel.Text = 'f(x,y) = ';
            app.eF_2 = uieditfield(app.FunoRealde2VariveisReaisPanel, 'text');
            app.eF_2.Value = 'x^2+y^2';
            app.bAtualizar_2 = uibutton(app.pDados_2, 'push');
            app.bAtualizar_2.ButtonPushedFcn = createCallbackFcn(app, @bAtualizar_2ButtonPushed, true);
            app.bAtualizar_2.Text = 'Atualizar';
            app.DerivaesNumricasButtonGroup = uibuttongroup(app.pDados_2);
            app.DerivaesNumricasButtonGroup.Title = 'Derivações Numéricas';
            app.DerivadaparcialordemayButton = uiradiobutton(app.DerivaesNumricasButtonGroup);
            app.DerivadaparcialordemayButton.Text = 'Derivada parcial ordem a y';
            app.DerivadaparcialordemaxButton = uiradiobutton(app.DerivaesNumricasButtonGroup);
            app.DerivadaparcialordemaxButton.Text = 'Derivada parcial ordem a x';
            app.DerivadaparcialordemaxButton.Value = true;
            app.eHarmonica = uieditfield(app.pDados_2, 'text');
            app.eHarmonica.Editable = 'off';
            app.YPanel = uipanel(app.pDados_2);
            app.YPanel.Title = 'Y';
            app.eH_3 = uieditfield(app.YPanel, 'numeric');
            app.eH_3.Value = 0.1;
            app.hLabel_3 = uilabel(app.YPanel);
            app.hLabel_3.Text = 'h';
            app.eB_3 = uieditfield(app.YPanel, 'numeric');
            app.eB_3.Value = 6.28318530717959;
            app.bLabel_3 = uilabel(app.YPanel);
            app.bLabel_3.Text = 'b';
            app.aLabel_3 = uilabel(app.YPanel);
            app.aLabel_3.Text = 'a';
            app.eA_3 = uieditfield(app.YPanel, 'numeric');
            app.XPanel = uipanel(app.pDados_2);
            app.XPanel.Title = 'X';
            app.eH_2 = uieditfield(app.XPanel, 'numeric');
            app.eH_2.Value = 0.1;
            app.hLabel_2 = uilabel(app.XPanel);
            app.hLabel_2.Text = 'h';
            app.eB_2 = uieditfield(app.XPanel, 'numeric');
            app.eB_2.Value = 6.28318530717959;
            app.bLabel_2 = uilabel(app.XPanel);
            app.bLabel_2.Text = 'b';
            app.aLabel_2 = uilabel(app.XPanel);
            app.aLabel_2.Text = 'a';
            app.eA_2 = uieditfield(app.XPanel, 'numeric');
            app.pResultados_2 = uipanel(app.tgFr2Vr);
            app.UIAxes_2 = uiaxes(app.pResultados_2);
            title(app.UIAxes_2, {'Gráfico de uma Função Real de 2 Variáveis Reais'; ''})
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            % Show the figure after all components are created
            app.MquinaClculoDiferencialeIntegralUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appMaquinaCDI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MquinaClculoDiferencialeIntegralUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MquinaClculoDiferencialeIntegralUIFigure)
        end
    end
end