classdef MaquinaProgressoes < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        LeftPanel              matlab.ui.container.Panel
        bAtualizar             matlab.ui.control.Button
        DropDown               matlab.ui.control.DropDown
        DropDownLabel          matlab.ui.control.Label
        bgProgressoes          matlab.ui.container.ButtonGroup
        rbGeometrica           matlab.ui.control.RadioButton
        rbAritmetica           matlab.ui.control.RadioButton
        DadosdaprogressoPanel  matlab.ui.container.Panel
        sN                     matlab.ui.control.Spinner
        nSpinnerLabel          matlab.ui.control.Label
        eR                     matlab.ui.control.NumericEditField
        rEditFieldLabel        matlab.ui.control.Label
        eA1                    matlab.ui.control.NumericEditField
        a_1EditFieldLabel      matlab.ui.control.Label
        UIAxes4                matlab.ui.control.UIAxes
        RightPanel             matlab.ui.container.Panel
        Panel_3                matlab.ui.container.Panel
        UITable                matlab.ui.control.Table
        Panel_2                matlab.ui.container.Panel
        UIAxesGraficos         matlab.ui.control.UIAxes
        Switch                 matlab.ui.control.Switch
        Lamp                   matlab.ui.control.Lamp
        eSoma                  matlab.ui.control.NumericEditField
        SomaEditFieldLabel     matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    methods (Access = public)
        
        function a = gerarPGeometrica(~,a_1,r,n)
            a = zeros(1,n);
            a(1) = a_1;
            for i = 1:n
                a(i) = a(1)*r^(i-1);
            end
        end
        
        function S = SomaPGeometrica(~,a)
            n = length(a);
            r = a(2)/a(1);
            S = a(1)*(r^n -1)/(r-1);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: bAtualizar
        function bAtualizarButtonPushed(app, event)
            a_1 = app.eA1.Value;
            r = app.eR.Value;
            n = app.sN.Value;

            escolhaBG = app.bgProgressoes.SelectedObject;
            escolha = find([app.rbAritmetica, app.rbGeometrica]==escolhaBG)
            switch escolha
                case 1
                    a = gerarPAritmetica_v4(a_1,r,n);
                    plot(app.UIAxesGraficos,a,"or");
                    S = SomaPAritmetica(a);
                case 2
                    a = gerarPGeometrica(app,a_1,r,n);
                    loglog(app.UIAxesGraficos,a,"or");
                    S = SomaPGeometrica(app,a);
            end
            app.UITable.Data = a;
            app.eSoma.Value = S;

            if S < 100
                app.Lamp.Color = "g";
            else
                app.Lamp.Color = [1,0,0]; % [r g b]
            end
            
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if value == "On"
                grid(app.UIAxesGraficos,"on")
            else
                grid(app.UIAxesGraficos,"off")
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {508, 508};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.UIAxes4 = uiaxes(app.LeftPanel);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.DadosdaprogressoPanel = uipanel(app.LeftPanel);
            app.DadosdaprogressoPanel.Title = 'Dados da progressão';
            app.a_1EditFieldLabel = uilabel(app.DadosdaprogressoPanel);
            app.a_1EditFieldLabel.Text = 'a_1 = ';
            app.eA1 = uieditfield(app.DadosdaprogressoPanel, 'numeric');
            app.eA1.Value = 1;
            app.rEditFieldLabel = uilabel(app.DadosdaprogressoPanel);
            app.rEditFieldLabel.Text = 'r =';
            app.eR = uieditfield(app.DadosdaprogressoPanel, 'numeric');
            app.eR.Value = 1;
            app.nSpinnerLabel = uilabel(app.DadosdaprogressoPanel);
            app.nSpinnerLabel.Text = 'n =';
            app.sN = uispinner(app.DadosdaprogressoPanel);
            app.sN.Limits = [2 Inf];
            app.sN.Value = 2;
            app.bgProgressoes = uibuttongroup(app.LeftPanel);
            app.bgProgressoes.Title = 'Progressões';
            app.rbAritmetica = uiradiobutton(app.bgProgressoes);
            app.rbAritmetica.Text = 'Aritmética';
            app.rbAritmetica.Value = true;
            app.rbGeometrica = uiradiobutton(app.bgProgressoes);
            app.rbGeometrica.Text = 'Geométrica';
            app.DropDownLabel = uilabel(app.LeftPanel);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.LeftPanel);
            app.bAtualizar = uibutton(app.LeftPanel, 'push');
            app.bAtualizar.ButtonPushedFcn = createCallbackFcn(app, @bAtualizarButtonPushed, true);
            app.bAtualizar.Text = 'Atualizar';
            app.RightPanel = uipanel(app.GridLayout);
            app.SomaEditFieldLabel = uilabel(app.RightPanel);
            app.SomaEditFieldLabel.Text = 'Soma = ';
            app.eSoma = uieditfield(app.RightPanel, 'numeric');
            app.eSoma.ValueDisplayFormat = '%e';
            app.eSoma.Editable = 'off';
            app.Lamp = uilamp(app.RightPanel);
            app.Switch = uiswitch(app.RightPanel, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Panel_2 = uipanel(app.RightPanel);
            app.UIAxesGraficos = uiaxes(app.Panel_2);
            app.UIAxesGraficos.XTick = 0;
            app.Panel_3 = uipanel(app.RightPanel);
            app.UITable = uitable(app.Panel_3);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MaquinaProgressoes

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end