classdef MaquinaCDI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        TabGroup             matlab.ui.container.TabGroup
        Tab1                 matlab.ui.container.Tab
        Tab2                 matlab.ui.container.Tab
        Panel                matlab.ui.container.Panel
        bExecutar            matlab.ui.control.Button
        eD                   matlab.ui.control.NumericEditField
        aEditField_4Label_3  matlab.ui.control.Label
        eH2                  matlab.ui.control.NumericEditField
        h2EditFieldLabel     matlab.ui.control.Label
        eC                   matlab.ui.control.NumericEditField
        aEditField_4Label_2  matlab.ui.control.Label
        eA                   matlab.ui.control.NumericEditField
        aEditField_4Label    matlab.ui.control.Label
        eB                   matlab.ui.control.NumericEditField
        bEditFieldLabel      matlab.ui.control.Label
        eH1                  matlab.ui.control.NumericEditField
        h1EditFieldLabel     matlab.ui.control.Label
        eFxy                 matlab.ui.control.EditField
        fxyEditFieldLabel    matlab.ui.control.Label
        UIAxesFxy            matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: bExecutar
        function bExecutarButtonPushed(app, event)
            syms z(x,y)
            strF = app.eFxy.Value;
            f = @(x,y) eval(vectorize(strF));
            a = app.eA.Value; h1 = app.eH1.Value; b = app.eB.Value;
            c = app.eC.Value; h2 = app.eH2.Value; d = app.eD.Value;

            erroFuncao = MException("MATLAB:Executar:BadF",...
                "Introduza uma função em x e y");
            %outras

            try
                try
                    z = f(x,y);
                    app.eFxy.BackgroundColor = "w";
                catch ME
                    app.eFxy.BackgroundColor = "r";
                    throw(erroFuncao);
                end

                [x,y] = meshgrid (a:h1:b, c:h2:d);
                z = f(x,y);
                %mesh(app.UIAxesFxy,x,y,z)
                surfc(app.UIAxesFxy,x,y,z)

            catch ME
                errordlg(ME.message,"ERRO", "modal");
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab1 = uitab(app.TabGroup);
            app.Tab1.Title = 'Função: y = f(x)';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Função: z = f(x,y)';
            app.UIAxesFxy = uiaxes(app.Tab2);
            title(app.UIAxesFxy, 'Title')
            xlabel(app.UIAxesFxy, 'X')
            ylabel(app.UIAxesFxy, 'Y')
            zlabel(app.UIAxesFxy, 'Z')
            app.Panel = uipanel(app.Tab2);
            app.Panel.Title = 'Panel';
            app.fxyEditFieldLabel = uilabel(app.Panel);
            app.fxyEditFieldLabel.Text = 'f(x,y) = ';
            app.eFxy = uieditfield(app.Panel, 'text');
            app.eFxy.Value = 'x^2+y^2';
            app.h1EditFieldLabel = uilabel(app.Panel);
            app.h1EditFieldLabel.Text = 'h1';
            app.eH1 = uieditfield(app.Panel, 'numeric');
            app.eH1.Value = 0.1;
            app.bEditFieldLabel = uilabel(app.Panel);
            app.bEditFieldLabel.Text = 'b';
            app.eB = uieditfield(app.Panel, 'numeric');
            app.eB.Value = 2;
            app.aEditField_4Label = uilabel(app.Panel);
            app.aEditField_4Label.Text = 'a';
            app.eA = uieditfield(app.Panel, 'numeric');
            app.eA.Value = -2;
            app.aEditField_4Label_2 = uilabel(app.Panel);
            app.aEditField_4Label_2.Text = 'c';
            app.eC = uieditfield(app.Panel, 'numeric');
            app.eC.Value = -2;
            app.h2EditFieldLabel = uilabel(app.Panel);
            app.h2EditFieldLabel.Text = 'h2';
            app.eH2 = uieditfield(app.Panel, 'numeric');
            app.eH2.Value = 0.1;
            app.aEditField_4Label_3 = uilabel(app.Panel);
            app.aEditField_4Label_3.Text = 'd';
            app.eD = uieditfield(app.Panel, 'numeric');
            app.eD.Value = 2;
            app.bExecutar = uibutton(app.Panel, 'push');
            app.bExecutar.ButtonPushedFcn = createCallbackFcn(app, @bExecutarButtonPushed, true);
            app.bExecutar.Text = 'Executar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MaquinaCDI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end