classdef MaquinaCDI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        TabGroup           matlab.ui.container.TabGroup
        yfxTab             matlab.ui.container.Tab
        zfxyTab            matlab.ui.container.Tab
        Panel              matlab.ui.container.Panel
        labelHarmonica     matlab.ui.control.Label
        UIMostrador        matlab.ui.control.UIAxes
        bAtualizar         matlab.ui.control.Button
        DadosdaFunoPanel   matlab.ui.container.Panel
        eC                 matlab.ui.control.NumericEditField
        aEditFieldLabel_2  matlab.ui.control.Label
        eH2                matlab.ui.control.NumericEditField
        eD                 matlab.ui.control.NumericEditField
        dLabel             matlab.ui.control.Label
        h2Label            matlab.ui.control.Label
        bLabel             matlab.ui.control.Label
        eB                 matlab.ui.control.NumericEditField
        eH1                matlab.ui.control.NumericEditField
        h1Label            matlab.ui.control.Label
        eA                 matlab.ui.control.NumericEditField
        aEditFieldLabel    matlab.ui.control.Label
        eFxy               matlab.ui.control.EditField
        fxyEditFieldLabel  matlab.ui.control.Label
        UIAxes             matlab.ui.control.UIAxes
    end

    
    methods (Access = public)
        
        function YN = isHarmonica(~, f)
            syms z(x,y)
            if diff(f,x,2)+diff(diff(f,y), y)==0
                YN = 1;
            else
                YN = 0;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: bAtualizar
        function bAtualizarButtonPushed(app, event)
            syms z(x,y)
            strF = app.eFxy.Value;
            f =@(x,y) eval(vectorize(strF));
            a = app.eA.Value; h1 = app.eH1.Value; b = app.eB.Value;
            c = app.eC.Value; h2 = app.eH2.Value; d = app.eD.Value;
            
            erroFuncao = MException("Matlab:bAtualizar:BadF",...
                                    " Introduza uma função em x e y");
            
            try
                try
                    z = f(x,y);
                    app.eFxy.BackgroundColor = "w";
                catch ME
                    app.eFxy.BackgroundColor = "r";
                    throw(erroFuncao)  
                end
                [x,y] = meshgrid(a:h1:b, c:h2:d);
                z = f(x,y);
                surfc(app.UIAxes,x,y,z)

                cla(app.UIMostrador)
                eqLaplace = ['$',...
                    ' \frac{\partial^2 f}{\partial x^2}+\frac{\partial^2 f}{\partial y^2}=0',...
                    '$']
                text(app.UIMostrador,0.1,0.5,eqLaplace,"Interpreter","latex","FontSize",18);


                %YN = isHarmonica(~, f);
                if isHarmonica(app,f)
                    textoHarmonica = "A função é harmónica";
                    corHarmonica = "g";
                else
                    textoHarmonica = "A função é não harmónica";
                    corHarmonica = "r";
                end

                app.labelHarmonica.Text = textoHarmonica;
                app.labelHarmonica.FontColor = corHarmonica;


            catch ME
                errordld(ME.message,"ERRO","modal") 
            end         
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.yfxTab = uitab(app.TabGroup);
            app.yfxTab.Title = 'y = f(x)';
            app.zfxyTab = uitab(app.TabGroup);
            app.zfxyTab.Title = 'z = f(x,y)';
            app.UIAxes = uiaxes(app.zfxyTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.DadosdaFunoPanel = uipanel(app.zfxyTab);
            app.DadosdaFunoPanel.Title = 'Dados da Função';
            app.fxyEditFieldLabel = uilabel(app.DadosdaFunoPanel);
            app.fxyEditFieldLabel.Text = 'f(x,y) =';
            app.eFxy = uieditfield(app.DadosdaFunoPanel, 'text');
            app.eFxy.Value = 'x^2+y^2';
            app.aEditFieldLabel = uilabel(app.DadosdaFunoPanel);
            app.aEditFieldLabel.Text = 'a';
            app.eA = uieditfield(app.DadosdaFunoPanel, 'numeric');
            app.eA.Value = -2;
            app.h1Label = uilabel(app.DadosdaFunoPanel);
            app.h1Label.Text = 'h1';
            app.eH1 = uieditfield(app.DadosdaFunoPanel, 'numeric');
            app.eH1.Value = 0.1;
            app.eB = uieditfield(app.DadosdaFunoPanel, 'numeric');
            app.eB.Value = 2;
            app.bLabel = uilabel(app.DadosdaFunoPanel);
            app.bLabel.Text = 'b';
            app.h2Label = uilabel(app.DadosdaFunoPanel);
            app.h2Label.Text = 'h2';
            app.dLabel = uilabel(app.DadosdaFunoPanel);
            app.dLabel.Text = 'd';
            app.eD = uieditfield(app.DadosdaFunoPanel, 'numeric');
            app.eD.Value = 2;
            app.eH2 = uieditfield(app.DadosdaFunoPanel, 'numeric');
            app.eH2.Value = 0.1;
            app.aEditFieldLabel_2 = uilabel(app.DadosdaFunoPanel);
            app.aEditFieldLabel_2.Text = 'c';
            app.eC = uieditfield(app.DadosdaFunoPanel, 'numeric');
            app.eC.Value = -2;
            app.bAtualizar = uibutton(app.zfxyTab, 'push');
            app.bAtualizar.ButtonPushedFcn = createCallbackFcn(app, @bAtualizarButtonPushed, true);
            app.bAtualizar.Text = 'Atualizar';
            app.Panel = uipanel(app.zfxyTab);
            app.UIMostrador = uiaxes(app.Panel);
            app.UIMostrador.XTick = [];
            app.UIMostrador.YTick = [];
            app.labelHarmonica = uilabel(app.Panel);
            app.labelHarmonica.Text = 'A função não é harmónica';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MaquinaCDI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end