classdef maquinaMNED < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        GridLayout         matlab.ui.container.GridLayout
        LeftPanel          matlab.ui.container.Panel
        bAtualizar         matlab.ui.control.Button
        ButtonGroup        matlab.ui.container.ButtonGroup
        Button3            matlab.ui.control.RadioButton
        Button2            matlab.ui.control.RadioButton
        Button             matlab.ui.control.RadioButton
        DadosdaPVIPanel    matlab.ui.container.Panel
        eY0                matlab.ui.control.NumericEditField
        y0Label            matlab.ui.control.Label
        eN                 matlab.ui.control.NumericEditField
        nLabel             matlab.ui.control.Label
        eB                 matlab.ui.control.NumericEditField
        bLabel             matlab.ui.control.Label
        eA                 matlab.ui.control.NumericEditField
        aLabel             matlab.ui.control.Label
        eF                 matlab.ui.control.EditField
        ftyEditFieldLabel  matlab.ui.control.Label
        RightPanel         matlab.ui.container.Panel
        Panel_2            matlab.ui.container.Panel
        UITable            matlab.ui.control.Table
        Panel              matlab.ui.container.Panel
        UIAxes             matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: bAtualizar
        function bAtualizarButtonPushed(app, event)
            syms y(t)
            strF = app.eF.Value;
            f = @(t,y) eval(vectorize(strF));
            a = app.eA.Value;
            b = app.eB.Value;
            n = app.eN.Value;
            y0 = app.eY0.Value;

            try
                sExata = dsolve(diff(y,t)==f(t,y),y(a)==y0);
                g =@(t) eval(vectorize(char(sExata)));

                [t,yEuler] = NEuler(f,a,b,n,y0);
                [~,yRK2] = NRK2(f,a,b,n,y0);

                yExata = g(t);
                erroEuler = abs(yExata-yEuler);
                erroRK2 = abs(yExata-yRK2);

                matrizResultados = [t.', yExata.', yEuler.',yRK2.',erroEuler.',erroRK2.'];
                tabelaResultados = array2table(matrizResultados,...
                "VariableNames",["t","Exata","Euler","RK2","erroEuler","erroRK2"]);

                plot(app.UIAxes,t,yExata,"-*r")
                hold (app.UIAxes,"on")
                plot(app.UIAxes,t,yEuler,"-og")
                plot(app.UIAxes,t,yRK2,"-+b")
                hold (app.UIAxes,"off")
                grid (app.UIAxes,"on")
                legend(app.UIAxes,"Exata","Euler","RK2")

                app.UITable.Data = tabelaResultados;
            catch ME
                errordlg(ME.message,"ERRO","modal")
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {353, 353};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DadosdaPVIPanel = uipanel(app.LeftPanel);
            app.DadosdaPVIPanel.Title = 'Dados da PVI';
            app.ftyEditFieldLabel = uilabel(app.DadosdaPVIPanel);
            app.ftyEditFieldLabel.Text = 'f(t,y)=';
            app.eF = uieditfield(app.DadosdaPVIPanel, 'text');
            app.eF.Value = 'y+exp(3*t)';
            app.aLabel = uilabel(app.DadosdaPVIPanel);
            app.aLabel.Text = 'a ';
            app.eA = uieditfield(app.DadosdaPVIPanel, 'numeric');
            app.bLabel = uilabel(app.DadosdaPVIPanel);
            app.bLabel.Text = 'b';
            app.eB = uieditfield(app.DadosdaPVIPanel, 'numeric');
            app.eB.Value = 1.5;
            app.nLabel = uilabel(app.DadosdaPVIPanel);
            app.nLabel.Text = 'n';
            app.eN = uieditfield(app.DadosdaPVIPanel, 'numeric');
            app.eN.Value = 2;
            app.y0Label = uilabel(app.DadosdaPVIPanel);
            app.y0Label.Text = 'y0';
            app.eY0 = uieditfield(app.DadosdaPVIPanel, 'numeric');
            app.eY0.Value = 2;
            app.ButtonGroup = uibuttongroup(app.LeftPanel);
            app.ButtonGroup.Title = 'Button Group';
            app.Button = uiradiobutton(app.ButtonGroup);
            app.Button.Text = 'Button';
            app.Button.Value = true;
            app.Button2 = uiradiobutton(app.ButtonGroup);
            app.Button2.Text = 'Button2';
            app.Button3 = uiradiobutton(app.ButtonGroup);
            app.Button3.Text = 'Button3';
            app.bAtualizar = uibutton(app.LeftPanel, 'push');
            app.bAtualizar.ButtonPushedFcn = createCallbackFcn(app, @bAtualizarButtonPushed, true);
            app.bAtualizar.Text = 'Atualizar';
            app.RightPanel = uipanel(app.GridLayout);
            app.Panel = uipanel(app.RightPanel);
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Panel_2 = uipanel(app.RightPanel);
            app.UITable = uitable(app.Panel_2);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = maquinaMNED

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end