classdef MaquinaMNED < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        FIcheiroMenu       matlab.ui.container.Menu
        SairMenu           matlab.ui.container.Menu
        AutorMenu          matlab.ui.container.Menu
        GridLayout         matlab.ui.container.GridLayout
        LeftPanel          matlab.ui.container.Panel
        bAtualizar_2       matlab.ui.control.Button
        bAtualizar         matlab.ui.control.Button
        bgMetodos          matlab.ui.container.ButtonGroup
        rbTodos            matlab.ui.control.RadioButton
        rbOutro            matlab.ui.control.RadioButton
        rbODE45            matlab.ui.control.RadioButton
        rbRK4              matlab.ui.control.RadioButton
        rbRK2              matlab.ui.control.RadioButton
        rbEulerM           matlab.ui.control.RadioButton
        rbEuler            matlab.ui.control.RadioButton
        DadosdoPVIPanel    matlab.ui.container.Panel
        eY0                matlab.ui.control.NumericEditField
        aEditFieldLabel_4  matlab.ui.control.Label
        eN                 matlab.ui.control.NumericEditField
        aEditFieldLabel_3  matlab.ui.control.Label
        eB                 matlab.ui.control.NumericEditField
        aEditFieldLabel_2  matlab.ui.control.Label
        eA                 matlab.ui.control.NumericEditField
        aEditFieldLabel    matlab.ui.control.Label
        eF                 matlab.ui.control.EditField
        ftyEditFieldLabel  matlab.ui.control.Label
        RightPanel         matlab.ui.container.Panel
        UIAxes             matlab.ui.control.UIAxes
        UITable            matlab.ui.control.Table
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            bAtualizarButtonPushed(app, [])
        end

        % Button pushed function: bAtualizar
        function bAtualizarButtonPushed(app, event)
            cla(app.UIAxes)
            syms y(t)
            strF = app.eF.Value;
            f = @(t,y) eval(vectorize(strF));
            a = app.eA.Value;
            b = app.eB.Value;
            n = app.eN.Value;
            y0 = app.eY0.Value;
 
            escolhabgMetodos = app.bgMetodos.SelectedObject;
            rbMetodoSelecionado = find([app.rbEuler,...
                                        app.rbEulerM,...
                                        app.rbRK2,...
                                        app.rbRK4,...
                                        app.rbODE45,...
                                        app.rbOutro,...
                                        app.rbTodos]==escolhabgMetodos);
 
            erroFuncao = MException("Matlab:bAtualizar:badF",...
                                    "Introduza uma função em t e y" );
 
            erroB = MException("Matlab:bAtualizar:badB",...
                                    "Introduza um b > a" );
            %outras
 
            try
                try
                    z = f(t,y);
                    app.eF.BackgroundColor = "w";
 
                catch ME
                    app.eF.BackgroundColor = "r";
                    throw(erroFuncao)
                end
 
                if ~(isreal(b) && b > a)
                    app.eB.BackgroundColor = "r";
                    throw(erroB)
                else
                     app.eB.BackgroundColor = "w";
 
                end
 
 
                sExata = dsolve(diff(y,t)==f(t,y),y(a)==y0);
                g =@(t) eval(vectorize(char(sExata)));
 
                [t,yEuler] = NEuler(f,a,b,n,y0);
                [~,yRK2] = NRK2(f,a,b,n,y0);
 
                yExata = g(t);
                erroEuler = abs(yExata-yEuler);
                erroRK2 = abs(yExata-yRK2);
 
                plot(app.UIAxes,t,yExata,"-*r")
                hold(app.UIAxes,"on")     
 
                switch rbMetodoSelecionado
                    case 1
                        plot(app.UIAxes,t,yEuler,"-og")
                        matrizResultados = [t.', yExata.', yEuler.',erroEuler.']; 
                        legendaGrafico = ["Exata","Euler"];
                        NomeColunasTabela = ["t","Exata","Euler","ErroEuler"];
                    case 2
                        warndlg("Método não implementado","AVISO","modal")
                    case 3
                        plot(app.UIAxes,t,yRK2,"-+b")
                        matrizResultados = [t.', yExata.', yRK2.',erroRK2.']; 
                        legendaGrafico = ["Exata","RK2"];
                        NomeColunasTabela = ["t","Exata","RK2","ErroRK2"]; 
                    case 4
                        warndlg("Método não implementado","AVISO","modal")                        
                    case 5
                        warndlg("Método não implementado","AVISO","modal")                        
                    case 6
                        warndlg("Método não implementado","AVISO","modal")                        
                    case 7
                        plot(app.UIAxes,t,yEuler,"-og")
                        plot(app.UIAxes,t,yRK2,"-+b")                        
                        matrizResultados = [t.', yExata.', yEuler.'...
                            yRK2.',erroEuler.',erroRK2.']; 
                        legendaGrafico = ["Exata","Euler","RK2"];
                        NomeColunasTabela = ["t","Exata","Euler", "RK2",...
                            "ErroEuler","erroRK2"];                        
 
                end
 
                grid(app.UIAxes,"on")
                legend(app.UIAxes,legendaGrafico)
 
                app.UITable.Data = array2table(matrizResultados);
                app.UITable.ColumnName = NomeColunasTabela;
 
            catch ME
                errordlg(ME.message,"ERRO","modal")
            end
        end

        % Menu selected function: AutorMenu
        function AutorMenuSelected(app, event)
            app2
        end

        % Menu selected function: SairMenu
        function SairMenuSelected(app, event)
            SN = questdlg("Quer sair??", "Sair", "Sim","Não","Sim");
            if strcmp(SN, "Sim")
                delete(app.UIFigure)
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {372, 372};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.FIcheiroMenu = uimenu(app.UIFigure);
            app.FIcheiroMenu.Text = 'FIcheiro';
            app.SairMenu = uimenu(app.FIcheiroMenu);
            app.SairMenu.MenuSelectedFcn = createCallbackFcn(app, @SairMenuSelected, true);
            app.SairMenu.Text = 'Sair';
            app.AutorMenu = uimenu(app.UIFigure);
            app.AutorMenu.MenuSelectedFcn = createCallbackFcn(app, @AutorMenuSelected, true);
            app.AutorMenu.Text = 'Autor';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DadosdoPVIPanel = uipanel(app.LeftPanel);
            app.DadosdoPVIPanel.Title = 'Dados do PVI';
            app.ftyEditFieldLabel = uilabel(app.DadosdoPVIPanel);
            app.ftyEditFieldLabel.Text = 'f(t,y) = ';
            app.eF = uieditfield(app.DadosdoPVIPanel, 'text');
            app.eF.Value = 'y+exp(3*t)';
            app.aEditFieldLabel = uilabel(app.DadosdoPVIPanel);
            app.aEditFieldLabel.Text = 'a';
            app.eA = uieditfield(app.DadosdoPVIPanel, 'numeric');
            app.aEditFieldLabel_2 = uilabel(app.DadosdoPVIPanel);
            app.aEditFieldLabel_2.Text = 'b';
            app.eB = uieditfield(app.DadosdoPVIPanel, 'numeric');
            app.eB.Value = 1.5;
            app.aEditFieldLabel_3 = uilabel(app.DadosdoPVIPanel);
            app.aEditFieldLabel_3.Text = 'n';
            app.eN = uieditfield(app.DadosdoPVIPanel, 'numeric');
            app.eN.Value = 2;
            app.aEditFieldLabel_4 = uilabel(app.DadosdoPVIPanel);
            app.aEditFieldLabel_4.Text = 'y0';
            app.eY0 = uieditfield(app.DadosdoPVIPanel, 'numeric');
            app.eY0.Value = 2;
            app.bgMetodos = uibuttongroup(app.LeftPanel);
            app.bgMetodos.Title = 'Métodos Numéricos';
            app.rbEuler = uiradiobutton(app.bgMetodos);
            app.rbEuler.Text = 'Euler';
            app.rbEuler.Value = true;
            app.rbEulerM = uiradiobutton(app.bgMetodos);
            app.rbEulerM.Text = 'Euler Melhorado';
            app.rbRK2 = uiradiobutton(app.bgMetodos);
            app.rbRK2.Text = 'RK2';
            app.rbRK4 = uiradiobutton(app.bgMetodos);
            app.rbRK4.Text = 'RK4';
            app.rbODE45 = uiradiobutton(app.bgMetodos);
            app.rbODE45.Text = 'ODE45';
            app.rbOutro = uiradiobutton(app.bgMetodos);
            app.rbOutro.Text = 'Outro';
            app.rbTodos = uiradiobutton(app.bgMetodos);
            app.rbTodos.Text = 'Todos os Métodos';
            app.bAtualizar = uibutton(app.LeftPanel, 'push');
            app.bAtualizar.ButtonPushedFcn = createCallbackFcn(app, @bAtualizarButtonPushed, true);
            app.bAtualizar.Text = 'Atualizar';
            app.bAtualizar_2 = uibutton(app.LeftPanel, 'push');
            app.bAtualizar_2.Text = 'Reset';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            xlabel(app.UIAxes, 't')
            ylabel(app.UIAxes, 'y')
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MaquinaMNED

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end